classdef MorphDPCMAc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Camera_ArduinoTab               matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        GasTreatmentScheduleButton      matlab.ui.control.Button
        TextArea_19                     matlab.ui.control.TextArea
        PortsButton                     matlab.ui.control.Button
        TextArea_20                     matlab.ui.control.TextArea
        SetupcamerasButton              matlab.ui.control.Button
        TextArea_21                     matlab.ui.control.TextArea
        PicturefrequencyButton          matlab.ui.control.Button
        TextArea_22                     matlab.ui.control.TextArea
        RUNButton_3                     matlab.ui.control.Button
        TimeTextAreaLabel               matlab.ui.control.Label
        TimeTextArea                    matlab.ui.control.TextArea
        SeedlingTextAreaLabel           matlab.ui.control.Label
        SeedlingTextArea                matlab.ui.control.TextArea
        FrameTextAreaLabel              matlab.ui.control.Label
        FrameTextArea                   matlab.ui.control.TextArea
        CameraTextAreaLabel             matlab.ui.control.Label
        CameraTextArea                  matlab.ui.control.TextArea
        SprayTreatmentScheduleButton    matlab.ui.control.Button
        TextArea_25                     matlab.ui.control.TextArea
        CloseArduinoPortsButton         matlab.ui.control.Button
        UploadSketchstoArduinosButton   matlab.ui.control.Button
        UploadSketchstoArduinosButton_2  matlab.ui.control.Button
        HypocotylTab                    matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        EditFieldMessage                matlab.ui.control.EditField
        TextArea_9                      matlab.ui.control.TextArea
        TextArea_10                     matlab.ui.control.TextArea
        TextArea_11                     matlab.ui.control.TextArea
        DropDownSectionsHY              matlab.ui.control.DropDown
        SwitchFRHY                      matlab.ui.control.Switch
        TextArea_12                     matlab.ui.control.TextArea
        DropDownPointsHY                matlab.ui.control.DropDown
        TextArea_13                     matlab.ui.control.TextArea
        DropDownSetsHY                  matlab.ui.control.DropDown
        TextArea_14                     matlab.ui.control.TextArea
        TextArea_15                     matlab.ui.control.TextArea
        SwitchVisHY                     matlab.ui.control.Switch
        SwitchMovHY                     matlab.ui.control.Switch
        TextArea_16                     matlab.ui.control.TextArea
        SelectlastframetoanalyzeButton_2  matlab.ui.control.Button
        TextArea_17                     matlab.ui.control.TextArea
        SwitchAngleHY                   matlab.ui.control.Switch
        TextArea_18                     matlab.ui.control.TextArea
        SelectfirstimageButton_2        matlab.ui.control.Button
        RUNButton_2                     matlab.ui.control.Button
        ListoffilesTextArea_3Label      matlab.ui.control.Label
        ListoffilesTextArea_3           matlab.ui.control.TextArea
        TextArea_23                     matlab.ui.control.TextArea
        MethodDropDown                  matlab.ui.control.DropDown
        RenameFilesButton               matlab.ui.control.Button
        AjustcontrastCheckBox_2         matlab.ui.control.CheckBox
        RootTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        EditField                       matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        DropDown                        matlab.ui.control.DropDown
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        Switch                          matlab.ui.control.Switch
        Switch_2                        matlab.ui.control.Switch
        Switch_3                        matlab.ui.control.Switch
        TextArea_5                      matlab.ui.control.TextArea
        SelectlastframetoanalyzeButton  matlab.ui.control.Button
        SelectfirstimageButton          matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ListoffilesTextAreaLabel        matlab.ui.control.Label
        ListoffilesTextArea             matlab.ui.control.TextArea
        TextArea_24                     matlab.ui.control.TextArea
        MethodDropDown_2                matlab.ui.control.DropDown
        RenameFilesButton_2             matlab.ui.control.Button
        AjustcontrastCheckBox           matlab.ui.control.CheckBox
        SmoothingTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        ListoffilesTextArea_2Label      matlab.ui.control.Label
        ListoffilesTextArea_2           matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        LoadfirstpicoftheexperimentButton  matlab.ui.control.Button
        LoadpicwhentreatmentstartedButton  matlab.ui.control.Button
        LoadpicwhentreatmentendedButton  matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        TextArea_7                      matlab.ui.control.TextArea
        LoadfirstpicforthisplantButton  matlab.ui.control.Button
        LoadgrowthfilecsvButton         matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        AddlastroottoAllRootsButton     matlab.ui.control.Button
        TextArea_8                      matlab.ui.control.TextArea
        SmoothingSplineofAllRootsButton  matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        Normalizegrowthto2hbeforetreatmentCheckBox  matlab.ui.control.CheckBox
        StartpointfornormalizedgrowthEditFieldLabel  matlab.ui.control.Label
        StartpointfornormalizedgrowthEditField  matlab.ui.control.NumericEditField
        TextArea_26                     matlab.ui.control.TextArea
        EndpointfornormalizedgrowthEditFieldLabel  matlab.ui.control.Label
        EndpointfornormalizedgrowthEditField  matlab.ui.control.NumericEditField
        PlotapointeveryxminutesEditFieldLabel  matlab.ui.control.Label
        PlotapointeveryxminutesEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        times={'240';'120';'240'};
        timesspraying={'240';'2'};
        ports={'COM3';'COM4';'COM5'};
        name;
        arduino;
        arduino2;
        arduino3;
        avrdude = {'C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\bin\avrdude'};
        avrdudeconf = {'C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\etc\avrdude.conf'};
        inoexe5 = {'C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray3.ino.hex'};
        inoexe = {'C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray.ino.hex'};
        segmentshy=1;
        numpunthy=10;
        setshy=1;
        reversehy=0;
        visualhy=0;
        angulacionhy=0;
        xhy=0;
        savehy=1;
        contadorhy=0;
        namecathy={};
        namescathy={};
        pathnamecathy={};
        objectPointcathy=[];
        objectPointscathy={};
        objectRegioncathy={};
        objectRegioncat1hy={};
        listofgenotypeshy=[];
        listofgenotypesroothy=[];
        cicloscathy=[];
        filenumbercathy=[];
        frequency={'6'};
        metodos='MaxBidirectionalError';
        metodosvalue=1;
        %smoothing
        countersm=0;
        addedsm=0;
        cellAsm={};
        concatsm=[];
        listsm=[];
        listofnamessm=[];
        listofgenotypessm=[];
        concatysm=[];
        concattsm=[];
        concatprimesm=[];
        names11sm=[];
        contadorsm=0;
        h1sm=[];
        h2sm=[];
        h3sm=[];
        h4sm=[];
        plantassm=0;
        timefsm=[];
        time0sm;
        timeETONsm;
        timeETOFFsm;
        timeFirstsm;
        tsm;
        trsm;
        startsm;
        finsm;
        originalsplitsm;
        totalNimagessm;
        filenamesm;
        pathnamesm;
        ysm;
        genotypesm;
        yysm;
        yyprimesm;
        fsm;
        cyclesm;
        xscalesm;
        fprimesm;
        mediassm;
        SEMsm;
        mediasprimesm;
        stdvprimesm;
        SEMprimesm;
        stdvsm;
        inicostart=120;
        inicioend=240;
        autonorm=1;
        intervalo=5;
        entro=0;
        
         %ROOTS
        FRAMEcorrection=0;
        segmentsRT=1;
        reverseRT=0;
        visualRT=0;
        saveRT=1;
        xRT=0;
        contadorRT=0;
        pathnamecatRT;
        namescatRT;
        namecatRT;
        listofgenotypesRT;
        listofgenotypesrootRT;
        filenumbercatRT;
        objectRegioncatRT;
        objectRegioncat1RT;
        cicloscatRT;
        metodosRT='MaxBidirectionalError';
        metodosRTvalue=1;

        
    end
    
    methods (Access = private)
        
function [results,angle,pattern]= growthcalculatorhy(app,puntosxcat,puntosycat,objectPoints,timefilename,start,~,visual)
window=100;%this is the width of the bin 
nimages=size(puntosxcat);
npuntos=nimages(1,1);
nimages=nimages(1,2);
numpreselected=numel(objectPoints)/2;
%minimumdistance=window;
accumulativedistance=[];
EachPuntoTotalGrowthcat=[];
accumulativegrowthcat=[];

distancecat0=[];
distancecatcat0=[];
increasedistancecat0=[];
%increasedistancecatcat0=[];
coordenadascat=[];
% keeppunto0=[];
% keeppuntoT=[];
% conta0=1;
angle=[];

videoFileReader = vision.VideoFileReader(timefilename);
%find the point that stay until the end
for im=1:start-1
    objectFrame = step(videoFileReader);
end
[temp0,originalpos0] = sort(puntosxcat(:,1), 'descend' );


%select points that are closests to the preselected points 
for nn=1:numpreselected
    minimumdistance=window;
    
    for n=1:npuntos   %from a set p;oint
    
        if puntosxcat(originalpos0(n,1),nimages)==puntosxcat(originalpos0(n,1),nimages-1)
        continue
        end
        if puntosycat(originalpos0(n,1),nimages)==puntosycat(originalpos0(n,1),nimages-1)
        continue
        end
    
     %find the points closests to the preselected points
   % distance0=sqrt(((puntosxcat(originalpos0(n,1),1)-puntosxcat(originalpos0(1,1),1))^2)+((puntosycat(originalpos0(n,1),1)-puntosycat(originalpos0(1,1),1))^2));
distance0=sqrt(((puntosxcat(originalpos0(n,1),1)-objectPoints(nn,1))^2)+((puntosycat(originalpos0(n,1),1)-objectPoints(nn,2))^2));
        if distance0<minimumdistance
    minimumdistance=distance0;
    coordenadas=originalpos0(n,1);
        end

    end
   coordenadascat=cat(1,coordenadascat,coordenadas);

end   


%% this calculates the distace between point1 and point2 in each frame 
%make nkept0 bins and piut the puntos with the right properties
    

for pp = 1:nimages
    hold(app.UIAxes_2,'on');
    if visual==1
    objectFrame = step(videoFileReader);
    imshow(objectFrame,'Parent',app.UIAxes_2);
    drawnow;
    end
    
    for ax=1:numpreselected-1   
        distance0=(sqrt(((puntosxcat(coordenadascat(ax+1,1),pp)-puntosxcat(coordenadascat(ax,1),pp))^2)+((puntosycat(coordenadascat(ax+1,1),pp)-puntosycat(coordenadascat(ax,1),pp))^2)));
        angle0=atan((puntosycat(coordenadascat(ax+1,1),pp)- puntosycat(coordenadascat(ax,1),pp))/(puntosxcat(coordenadascat(ax+1,1),pp)-puntosxcat(coordenadascat(ax,1),pp)))*180/pi;
%         if angle0<0;
%             angle0=90+(90+angle0);
%         end
if visual==1
     plot(app.UIAxes_2,puntosxcat(coordenadascat(ax,1),pp),puntosycat(coordenadascat(ax,1),pp),'c*');
% %            plot(puntosxcat(yy1,pp),puntosycat(yy1,pp),'ro')
 plot (app.UIAxes_2,[puntosxcat(coordenadascat(ax+1,1),pp) puntosxcat(coordenadascat(ax,1),pp)],[puntosycat(coordenadascat(ax+1,1),pp) puntosycat(coordenadascat(ax,1),pp)],'r')

        drawnow;
end
angle=cat(1,angle,angle0);  
distancecat0=cat(2,distancecat0,distance0);
    end
    accumulativedistance=cat(1,accumulativedistance,distancecat0);
    distancecat0=sum(distancecat0,2);
%      pause(1);
     distancecatcat0=cat(2,distancecatcat0,distancecat0);
     distancecat0=[];
hold(app.UIAxes_2,'off');
end
    

%this calculates the increase in the distance among all the points between each frame 

    for pp = 1:nimages-1
        increasedistance0=distancecatcat0(1,pp+1)-distancecatcat0(1,pp);
        increasedistancecat0=cat(2,increasedistancecat0,increasedistance0);

    end
% increasedistancecatcat0=cat(1,increasedistancecatcat0,increasedistancecat0);
% increasedistancecat0=[];
%EachPuntoTotallength=sum(accumulativedistance,2);
for nm=1:nimages-1
    accumulativegrowth=(accumulativedistance((nm+1),:)-accumulativedistance((nm),:));
    EachPuntoTotalGrowthcat=cat(1,EachPuntoTotalGrowthcat,accumulativegrowth);
    accumulativegrowthcat=cat(1,accumulativegrowthcat,accumulativegrowth);
end


EachPuntoTotalGrowthcat=sum(EachPuntoTotalGrowthcat,1);

increasedistancecat0=increasedistancecat0.';
results=sum(increasedistancecat0,2);
totalgrowth=sum(results,1);
percentageGrowth=floor(EachPuntoTotalGrowthcat*100/totalgrowth);
pattern=accumulativegrowthcat*100/totalgrowth;
npuntos=numel(EachPuntoTotalGrowthcat);
hold(app.UIAxes_2,'on');
for np=1:npuntos
    percentageGrowthtxt=num2str(percentageGrowth(1,npuntos+1-np));
    if visual==1
text(app.UIAxes_2,double(puntosxcat(coordenadascat(ax+1-np,1),pp))+5,double(puntosycat(coordenadascat(ax+1-np,1),pp))-20,percentageGrowthtxt,'Color','red','FontSize',14);
    end
end
end


function [X,ndx] = natsortfiles(app,X,varargin)

%
assert(iscell(X),'First input <X> must be a cell array.')
tmp = cellfun('isclass',X,'char') & cellfun('size',X,1)<2 & cellfun('ndims',X)<3;
assert(all(tmp(:)),'First input <X> must be a cell array of strings (1xN character).')
%
%% Split and Sort File Names/Paths %%
%
% Split full filepaths into file [path,name,extension]:
[pth,nam,ext] = cellfun(@fileparts,X(:),'UniformOutput',false);
% Split path into {dir,subdir,subsubdir,...}:
pth = regexp(pth,filesep,'split'); % OS dependent
%pth = regexp(pth,'[/\\]','split'); % either / or \
len = cellfun('length',pth);
vec(1:numel(len)) = {''};
%
% Natural-order sort of the file extension, file name, and directory names:
[~,ndx] = app.natsort(ext,varargin{:});
[~,ind] = app.natsort(nam(ndx),varargin{:});
ndx = ndx(ind);
for k = max(len):-1:1
	idx = len>=k;
	vec(~idx) = {''};
	vec(idx) = cellfun(@(c)c(k),pth(idx));
	[~,ind] = app.natsort(vec(ndx),varargin{:});
	ndx = ndx(ind);
end
%
% Return the sorted array and indices:
ndx = reshape(ndx,size(X));
X = X(ndx);
%
end
        
function [X,ndx,dbg] = natsort(app,X,xpr,varargin) %#ok<*SPERR>

%
assert(iscell(X),'First input <X> must be a cell array.')
tmp = cellfun('isclass',X,'char') & 2>cellfun('size',X,1) & 3>cellfun('ndims',X);
assert(all(tmp(:)),'First input <X> must be a cell array of strings (1xN character).')
%
% Regular expression:
if nargin<3 || isnumeric(xpr)&&isempty(xpr)
	xpr = '\d+';
else
	assert(ischar(xpr)&&isrow(xpr),'Second input <xpr> must be a regular expression.')
end
%
% Optional arguments:
tmp = cellfun('isclass',varargin,'char') & 1==cellfun('size',varargin,1) & 2==cellfun('ndims',varargin);
assert(all(tmp(:)),'All optional arguments must be strings (1xN character).')
% Character case matching:
MatL = strcmpi(varargin,'matchcase');
CasL = strcmpi(varargin,'ignorecase')|MatL;
% Sort direction:
DesL = strcmpi(varargin,'descend');
DirL = strcmpi(varargin,'ascend')|DesL;
% Relative sort-order of numbers compared to characters:
BefL = strcmpi(varargin,'beforechar');
AftL = strcmpi(varargin,'afterchar');
RsoL = strcmpi(varargin,'asdigit')|BefL|AftL;
% SSCANF conversion format:
FmtL = ~(CasL|DirL|RsoL);
%
if nnz(DirL)>1
	error('Sort direction is overspecified:%s\b.',sprintf(' ''%s'',',varargin{DirL}))
end
%
if nnz(RsoL)>1
	error('Relative sort-order is overspecified:%s\b.',sprintf(' ''%s'',',varargin{RsoL}))
end
%
FmtN = nnz(FmtL);
if FmtN>1
	error('Overspecified optional arguments:%s\b.',sprintf(' ''%s'',',varargin{FmtL}))
end
%
%% Split Strings %%
%
% Split strings into number and remaining substrings:
[MtS,MtE,MtC,SpC] = regexpi(X(:),xpr,'start','end','match','split',varargin{CasL});
%
% Determine lengths:
MtcD = cellfun(@minus,MtE,MtS,'UniformOutput',false);
LenZ = cellfun('length',X(:))-cellfun(@sum,MtcD);
LenY = max(LenZ);
LenX = numel(MtC);
%
dbg = cell(LenX,LenY);
NuI = false(LenX,LenY);
ChI = false(LenX,LenY);
ChA = char(double(ChI));
%
ndx = 1:LenX;
for k = ndx(LenZ>0)
	% Determine indices of numbers and characters:
	ChI(k,1:LenZ(k)) = true;
	if ~isempty(MtS{k})
		tmp = MtE{k} - cumsum(MtcD{k});
		dbg(k,tmp) = MtC{k};
		NuI(k,tmp) = true;
		ChI(k,tmp) = false;
	end
	% Transfer characters into char array:
	if any(ChI(k,:))
		tmp = SpC{k};
		ChA(k,ChI(k,:)) = [tmp{:}];
	end
end
%
%% Convert Number Substrings %%
%
if FmtN % One format specifier
	fmt = varargin{FmtL};
	err = ['Format specifier results in an empty output from sscanf: ''',fmt,''''];
	P = '(?<!%)(%%)*%'; % match an odd number of % characters.
	[T,S] = regexp(fmt,[P,'(\d*)(b|d|i|u|o|x|f|e|g|l(d|i|u|o|x))'],'tokens','split');
	assert(isscalar(T),'Unsupported optional argument: ''%s''',fmt)
	assert(isempty(T{1}{2}),'Format specifier cannot include field-width: ''%s''',fmt)
	switch T{1}{3}(1)
		case 'b' % binary
			fmt = regexprep(fmt,[P,'(\*?)b'],'$1%$2[01]');
			val = dbg(NuI);
			if numel(S{1})<2 || ~strcmpi('0B',S{1}(end-1:end))
				% Remove '0B' if not specified in the format string:
				val = regexprep(val,'(0B)?([01]+)','$2','ignorecase');
			end
			val = cellfun(@(s)sscanf(s,fmt),val, 'UniformOutput',false);
			assert(~any(cellfun('isempty',val)),err)
			NuA(NuI) = cellfun(@(s)sum(pow2(s-48,numel(s)-1:-1:0)),val);
		case 'l' % 64-bit
			NuA(NuI) = cellfun(@(s)sscanf(s,fmt),dbg(NuI)); %slow!
		otherwise % double
			NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),[fmt,'\v']); % fast!
	end
else % No format specifier -> double
	NuA(NuI) = sscanf(sprintf('%s\v',dbg{NuI}),'%f\v');
end
% Note: NuA's class is determined by SSCANF.
NuA(~NuI) = 0;
NuA = reshape(NuA,LenX,LenY);
%
%% Debugging Array %%
%
if nargout>2
	for k = reshape(find(NuI),1,[])
		dbg{k} = NuA(k);
	end
	for k = reshape(find(ChI),1,[])
		dbg{k} = ChA(k);
	end
end
%
%% Sort %%
%
if ~any(MatL) % ignorecase
	ChA = upper(ChA);
end
%
ide = ndx.';
% From the last column to the first...
for n = LenY:-1:1
	% ...sort the characters and number values:
	[C,idc] = sort(ChA(ndx,n),1,varargin{DirL});
	[~,idn] = sort(NuA(ndx,n),1,varargin{DirL});
	% ...keep only relevant indices:
	jdc = ChI(ndx(idc),n); % character
	jdn = NuI(ndx(idn),n); % number
	jde = ~ChI(ndx,n)&~NuI(ndx,n); % empty
	% ...define the sort-order of numbers and characters:
	jdo = any(AftL)|(~any(BefL)&C<48);
	% ...then combine these indices in the requested direction:
	if any(DesL) % descending
		idx = [idc(jdc&~jdo);idn(jdn);idc(jdc&jdo);ide(jde)];
	else % ascending
		idx = [ide(jde);idc(jdc&jdo);idn(jdn);idc(jdc&~jdo)];
	end
	ndx = ndx(idx);
end
%
ndx  = reshape(ndx,size(X));
X = X(ndx);
%
end
        
function [results,anglechangecat]= growthcalculatorRT(app,puntosxcat,puntosx1cat,puntosycat,puntosy1cat,timefilename,start,visual)

% nimages=size(puntosxcat);
% npuntos=nimages(1,1);
% nimages=nimages(1,2);
% incxcat=[];
% incycat=[];
% incxcatf=[];
% incycatf=[];
% growthT1=[];
% growth1=[];
% 
% 
% 
% nimages1=size(puntosx1cat);
% npuntos1=nimages1(1,1);
% nimages1=nimages1(1,2);
% incxcat1=[];
% incycat1=[];
% incxcatf1=[];
% incycatf1=[];
% angle0cat=[];
% angle1cat=[];
% puntosx1catcat=[];
% puntosy1catcat=[];
% meanpuntosxcat=[];
% meanpuntosycat=[];
% meanpuntosxcatcat=[];
% meanpuntosycatcat=[];
% anglechangecat=[];
% allpuntosx=[];
% allpuntosy=[];
% meanangle1catcat=[];
% 
% correctedxcat=[];
% correctedycat=[];
% 
% 
% videoFileReader = vision.VideoFileReader(timefilename);
% %find the point that stay until the end
% for im=1:start-1
%     objectFrame = step(videoFileReader);
% end
% 
% %% this calculates the average displacement (x and y) due to the camera movement
% 
% for p=1:npuntos
%     for pp = 1:nimages-1
%         incx=puntosxcat(p,pp+1)-puntosxcat(p,pp);
%         incy=puntosycat(p,pp+1)-puntosycat(p,pp);
%         incxcat=cat(2,incxcat,incx);
%         incycat=cat(2,incycat,incy);
%         
%         
%     end
%         incxcatf=cat(1,incxcatf,incxcat);
%         incycatf=cat(1,incycatf,incycat);
%         incxcat=[];
%         incycat=[];
%         
% end
% 
% 
% incxcatf=incxcatf.';
% incycatf=incycatf.';
% 
% %%selects 10 random points
% %ncol = 10;
% %xcol = randperm(size(incxcatf,2),ncol);
% %    columnsx = incxcatf(:,xcol);
% %    columnsy = incycatf(:,xcol);
% %    avgincx=mean(columnsx,2);%average dx of all points in each image
% %    avgincy=mean(columnsy,2);%average dy of all points in each image 
% 
% 
% avgincx=mean(incxcatf,2);%average dx of all points in each image
% avgincy=mean(incycatf,2);%average dy of all points in each image 
% 
% %%this calculates the corrected displacement for the tracked points and
% %%uses that to calculate the corrected growth
% 
% for pa=1:npuntos1
%     for ppa = 1:(nimages1-1)
%         incx1=puntosx1cat(pa,ppa+1)-puntosx1cat(pa,ppa);
%         incy1=puntosy1cat(pa,ppa+1)-puntosy1cat(pa,ppa);
%         incxcat1=cat(2,incxcat1,incx1);
%         incycat1=cat(2,incycat1,incy1);
%         
%         inc1=sqrt(incx1^2+incy1^2);
%         growth1=cat(1,growth1,inc1);
%     end
%         incxcatf1=cat(1,incxcatf1,incxcat1);
%         incycatf1=cat(1,incycatf1,incycat1);
%         incxcat1=[];
%         incycat1=[];
%         
%         growthT1=cat(2,growthT1,growth1);
%         growth1=[];
%     
% end
% 
% 
% incxcatf1=incxcatf1.';
% incycatf1=incycatf1.';
% 
% sumgrowth1=sum(growthT1,1);
% 
% [temp1,originalpos1] = sort(sumgrowth1, 'descend' );% has the sorted points with max growth
% 
% %%this calculates the average of the 5 best points
% pn=originalpos1(1:10);
% for ppn=1:10 %select top 10 points
% incxcatfcorrected=incxcatf1(:,pn(1,ppn))-avgincx;
% incycatfcorrected=incycatf1(:,pn(1,ppn))-avgincy;  
% 
%     correctedxcat=cat(2,correctedxcat,incxcatfcorrected);
%     correctedycat=cat(2,correctedycat,incycatfcorrected);  
% end
% if visual==1
%  for im=1:nimages-1
%     objectFrame = step(videoFileReader);
%     hold(app.UIAxes,'on');
%     imshow(objectFrame,'Parent',app.UIAxes);
%       drawnow;
%      plot(mean(puntosxcat(:,im)),mean(puntosycat(:,im)),'c*','Parent',app.UIAxes);
%      angle0=atan(mean(puntosycat(:,im+1))- mean(puntosycat(:,im)))/(mean(puntosxcat(:,im+1))-mean(puntosxcat(:,im)))*180/pi;
%      angle0cat=cat(2,angle0cat,angle0);
%      for pr=1:10
%      puntosx1catcat=cat(2,puntosx1catcat,puntosx1cat(pn(1,pr),im));
%      puntosy1catcat=cat(2,puntosy1catcat,puntosy1cat(pn(1,pr),im));
%      end
%      plot(mean(puntosx1catcat,2),mean(puntosy1catcat,2),'c*','Parent',app.UIAxes);
%      puntosx1catcat=[];
%      puntosy1catcat=[];
%     drawnow;
%  end
% end
% 
% for im=1:nimages
% 
%   for pr=1:10
%      puntosx1catcat=cat(2,puntosx1catcat,puntosx1cat(pn(1,pr),im));
%      puntosy1catcat=cat(2,puntosy1catcat,puntosy1cat(pn(1,pr),im));
%   end  
% %    meanpuntosxcat=mean(puntosx1catcat,2);
% %    meanpuntosycat=mean(puntosy1catcat,2); 
% %    meanpuntosxcatcat= cat(2,meanpuntosxcatcat,meanpuntosxcat);
% %    meanpuntosycatcat= cat(2,meanpuntosycatcat,meanpuntosycat);
% %    meanpuntosxcat=[];
% %    meanpuntosycat=[];
% allpuntosx=cat(1,allpuntosx,puntosx1catcat);
% allpuntosy=cat(1,allpuntosy,puntosy1catcat);
% puntosx1catcat=[];
% puntosy1catcat=[];
%    
% end
% 
% 
% 
% 
% 
% 
% % for im=1:nimages-5
% %     angle1=atan((meanpuntosycatcat(1,im+5)- meanpuntosycatcat(1,im))/(meanpuntosxcatcat(1,im+5)- meanpuntosxcatcat(1,im)))*180/pi;   
% %     angle1cat=cat(1,angle1cat,angle1);
% % end
% % for im=1:nimages-6
% %    anglechange=angle1cat(im+1,1)-angle1cat(im,1);
% %     anglechangecat=cat(1,anglechangecat,anglechange);
% % end
% 
% 
% for im=1:nimages-5
%     for pr=1:10
%     angle1=atan((allpuntosy(im+5,pr)- allpuntosy(im,pr))/(allpuntosx(im+5,pr)-allpuntosy(im,pr)))*180/pi;   
%     angle1cat=cat(1,angle1cat,angle1);
%     end
%     meanangle1cat=mean(angle1cat,1);
%     meanangle1catcat=cat(2,meanangle1catcat,meanangle1cat);
%     angle1cat=[];
% end
% 
% for im=1:nimages-6
%    anglechange=meanangle1catcat(1,im+1)-meanangle1catcat(1,im);
%     anglechangecat=cat(1,anglechangecat,anglechange);
% end
% 
% 
% 
% avgcorrectedx=mean(correctedxcat,2);
% avgcorrectedy=mean(correctedycat,2);
% 
% results=sqrt(avgcorrectedx.^2+avgcorrectedy.^2);

nimages=size(puntosxcat);
npuntos=nimages(1,1);
nimages=nimages(1,2);
incxcat=[];
incycat=[];
incxcatf=[];
incycatf=[];
growthT1=[];
growth1=[];


nimages1=size(puntosx1cat);
npuntos1=nimages1(1,1);
nimages1=nimages1(1,2);
incxcat1=[];
incycat1=[];
incxcatf1=[];
incycatf1=[];
angle0cat=[];
angle1cat=[];
puntosx1catcat=[];
puntosy1catcat=[];
meanpuntosxcat=[];
meanpuntosycat=[];
meanpuntosxcatcat=[];
meanpuntosycatcat=[];
anglechangecat=[];
allpuntosx=[];
allpuntosy=[];
meanangle1catcat=[];

correctedxcat=[];
correctedycat=[];


videoFileReader = vision.VideoFileReader(timefilename);
%find the point that stay until the end
for im=1:start-1
    objectFrame = step(videoFileReader);
end

%% this calculates the average displacement (x and y) due to the camera movement

for p=1:npuntos
    for pp = 1:nimages-1
        incx=puntosxcat(p,pp+1)-puntosxcat(p,pp);
        incy=puntosycat(p,pp+1)-puntosycat(p,pp);
        incxcat=cat(2,incxcat,incx);
        incycat=cat(2,incycat,incy);
        
        
    end
        incxcatf=cat(1,incxcatf,incxcat);
        incycatf=cat(1,incycatf,incycat);
        incxcat=[];
        incycat=[];
        
end


incxcatf=incxcatf.';
incycatf=incycatf.';

%%selects 10 random points
%ncol = 10;
%xcol = randperm(size(incxcatf,2),ncol);
%    columnsx = incxcatf(:,xcol);
%    columnsy = incycatf(:,xcol);
%    avgincx=mean(columnsx,2);%average dx of all points in each image
%    avgincy=mean(columnsy,2);%average dy of all points in each image 


indx=incxcatf==0;
indy=incycatf==0;
smax=sum(indx,1);
smay=sum(indy,1);
numerodepuntosx=sum(smax==0);
numerodepuntosy=sum(smay==0);

[temp2,originalpos2] = sort(smax, 'ascend' );
[temp3,originalpos3] = sort(smay, 'ascend' );

testx=incxcatf(:,originalpos2(1:numerodepuntosx));
testy=incycatf(:,originalpos3(1:numerodepuntosy));

avgincx=mean(testx,2);%average dx of all points in each image
avgincy=mean(testy,2);%average dy of all points in each image 


% avgincx=mean(incxcatf,2);%average dx of all points in each image
% avgincy=mean(incycatf,2);%average dy of all points in each image 

%%this calculates the corrected displacement for the tracked points and
%%uses that to calculate the corrected growth

for pa=1:npuntos1
    for ppa = 1:(nimages1-1)
        incx1=puntosx1cat(pa,ppa+1)-puntosx1cat(pa,ppa);
        incy1=puntosy1cat(pa,ppa+1)-puntosy1cat(pa,ppa);
        incxcat1=cat(2,incxcat1,incx1);
        incycat1=cat(2,incycat1,incy1);
        
        inc1=sqrt(incx1^2+incy1^2);
        growth1=cat(1,growth1,inc1);
    end
        incxcatf1=cat(1,incxcatf1,incxcat1);
        incycatf1=cat(1,incycatf1,incycat1);
        incxcat1=[];
        incycat1=[];
        
        growthT1=cat(2,growthT1,growth1);
        growth1=[];
    
end


incxcatf1=incxcatf1.';
incycatf1=incycatf1.';

sumgrowth1=sum(growthT1,1);

[temp1,originalpos1] = sort(sumgrowth1, 'descend' );% has the sorted points with max growth

%%this calculates the average of the 5 best points
pn=originalpos1(1:10);
for ppn=1:10 %select top 10 points
incxcatfcorrected=incxcatf1(:,pn(1,ppn))-avgincx;
incycatfcorrected=incycatf1(:,pn(1,ppn))-avgincy;  

    correctedxcat=cat(2,correctedxcat,incxcatfcorrected);
    correctedycat=cat(2,correctedycat,incycatfcorrected);  
end


if visual==1
 for im=1:nimages-1
    objectFrame = step(videoFileReader);
     hold(app.UIAxes,'on');
    imshow(objectFrame, 'Parent',app.UIAxes);
      drawnow;
     plot(app.UIAxes,mean(puntosxcat(:,im)),mean(puntosycat(:,im)),'c*');
     angle0=atan(mean(puntosycat(:,im+1))- mean(puntosycat(:,im)))/(mean(puntosxcat(:,im+1))-mean(puntosxcat(:,im)))*180/pi;
     angle0cat=cat(2,angle0cat,angle0);
     for pr=1:10
     puntosx1catcat=cat(2,puntosx1catcat,puntosx1cat(pn(1,pr),im));
     puntosy1catcat=cat(2,puntosy1catcat,puntosy1cat(pn(1,pr),im));
     end
     plot(app.UIAxes,mean(puntosx1catcat,2),mean(puntosy1catcat,2),'c*');
     puntosx1catcat=[];
     puntosy1catcat=[];
    drawnow;
 end
end

for im=1:nimages

  for pr=1:10
     puntosx1catcat=cat(2,puntosx1catcat,puntosx1cat(pn(1,pr),im));
     puntosy1catcat=cat(2,puntosy1catcat,puntosy1cat(pn(1,pr),im));
  end  
%    meanpuntosxcat=mean(puntosx1catcat,2);
%    meanpuntosycat=mean(puntosy1catcat,2); 
%    meanpuntosxcatcat= cat(2,meanpuntosxcatcat,meanpuntosxcat);
%    meanpuntosycatcat= cat(2,meanpuntosycatcat,meanpuntosycat);
%    meanpuntosxcat=[];
%    meanpuntosycat=[];
allpuntosx=cat(1,allpuntosx,puntosx1catcat);
allpuntosy=cat(1,allpuntosy,puntosy1catcat);
puntosx1catcat=[];
puntosy1catcat=[];
   
end


% for im=1:nimages-5
%     angle1=atan((meanpuntosycatcat(1,im+5)- meanpuntosycatcat(1,im))/(meanpuntosxcatcat(1,im+5)- meanpuntosxcatcat(1,im)))*180/pi;   
%     angle1cat=cat(1,angle1cat,angle1);
% end
% for im=1:nimages-6
%    anglechange=angle1cat(im+1,1)-angle1cat(im,1);
%     anglechangecat=cat(1,anglechangecat,anglechange);
% end


for im=1:nimages-5
    for pr=1:10
    angle1=atan((allpuntosy(im+5,pr)- allpuntosy(im,pr))/(allpuntosx(im+5,pr)-allpuntosy(im,pr)))*180/pi;   
    angle1cat=cat(1,angle1cat,angle1);
    end
    meanangle1cat=mean(angle1cat,1);
    meanangle1catcat=cat(2,meanangle1catcat,meanangle1cat);
    angle1cat=[];
end

for im=1:nimages-6
   anglechange=meanangle1catcat(1,im+1)-meanangle1catcat(1,im);
    anglechangecat=cat(1,anglechangecat,anglechange);
end


avgcorrectedx=mean(correctedxcat,2);
avgcorrectedy=mean(correctedycat,2);

results=sqrt(avgcorrectedx.^2+avgcorrectedy.^2);

end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            arduinoim=imread('arduino.jpg');
            imshow(arduinoim,'Parent',app.UIAxes_3);
        end

        % Button pushed function: GasTreatmentScheduleButton
        function GasTreatmentScheduleButtonPushed(app, event)
            prompt = {'Input the time intervals in minutes: '};
            title = 'Input';
            dims = [1 35];
            definput = {'240 120 240'};
            x1 = inputdlg(prompt,title,dims,definput);
            app.times=split(x1(1,1),' ');
            %app.times=app.times;
        end

        % Button pushed function: PortsButton
        function PortsButtonPushed(app, event)
prompt = {'Input the ports for camera1, camera2 and valves: '};
title = 'Input';
dims = [1 35];
definput = {'COM3 COM4 COM5'};
x1 = inputdlg(prompt,title,dims,definput);
app.ports=split(x1(1,1),' ');
        end

        % Button pushed function: SetupcamerasButton
        function SetupcamerasButtonPushed(app, event)

[filename, pathname] = uiputfile({'*.tif';'*.jpg';'*.jpeg'},'File Selector');
app.name = strcat(pathname, filename);

app.arduino=serial(app.ports(1,1),'BaudRate',9600);%check the ports are the correct ones
app.arduino2=serial(app.ports(2,1),'BaudRate',9600);%check the ports are the correct ones
% tiempo=cell2mat(app.times(:,1));
% tiempo=str2num(tiempo);
tiempospray1=cell2mat(app.timesspraying(1,1));
tiempospray2=cell2mat(app.timesspraying(2,1));
tiempospray1=str2num(tiempospray1);     
tiempospray2=str2num(tiempospray2);     
if str2num(cell2mat(app.times(1,1)))==0 && str2num(cell2mat(app.times(2,1)))==0 && str2num(cell2mat(app.times(3,1)))==0 && tiempospray1==0 && tiempospray1==0
   
else
app.arduino3=serial(app.ports(3,1),'BaudRate',9600);%check the ports are the correct ones
fopen(app.arduino3);
duration=tiempospray2;
duration=num2str(duration);
duration=strcat(duration,'\n');
%pause(3);
%fprintf(app.arduino3,'%c','T');
pause(2);
fprintf(app.arduino3,'%s',duration);
% pause(2);
% pasoduration=fscanf(app.arduino3,'%d')
%paso

end
fopen(app.arduino);
fopen(app.arduino2);

imaqtool;
        end

        % Button pushed function: PicturefrequencyButton
        function PicturefrequencyButtonPushed(app, event)
prompt = {'Input the picture frequency: '};
title = 'Input';
dims = [1 35];
definput = {'6'};
app.frequency = inputdlg(prompt,title,dims,definput);


        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)

seedling=0;
seedling2=0;
frame=0;
frame2=0;
airstarted=0;
ethylenestarted=0;
recoverystarted=0;
recoveryended=0;
spraystarted=0;
warning('off','all');
a=0;
elements=numel(app.times);
airstartedcat=[];
seedlingcat=[];
framecat=[];
camera1cat=[];
cuentatiempo=1;
seedlingsinA=10000;
seedlingsinB=10000;
seedlingnumber=0;
seedlingnumber2=0;

tiempo0=0;
tiempocat=[];
contador=1;
video=load('vid');
vid1=video.vid1;
vid2=video.vid2;
app.frequency=str2num(app.frequency{1})*60;
app.frequency=num2str(app.frequency);
app.frequency=strcat(app.frequency,'\n');
pause(2);
fprintf(app.arduino,'%s',app.frequency);
pause(2);
fprintf(app.arduino2,'%s',app.frequency);


%arduino.TimeOut = 10000;
%arduino2.TimeOut = 10000;
%arduino3.TimeOut = 10000;
tic;
while a<100 

 for p=1:elements
     tiempo=cell2mat(app.times(p,1));
     tiempo=str2num(tiempo);
     tiempo=60*tiempo;
     tiempo=tiempo0+tiempo;
     tiempocat=cat(2,tiempocat,tiempo);
 end
 spraystarts=cell2mat(app.timesspraying(1,1));
 spraystarts=str2num(spraystarts);
 spraystarts=60*spraystarts;
 spraystarts=tiempo0+spraystarts;
 
 if toc>=spraystarts && spraystarted==0 && seedlingsinB==seedling2 && seedlingsinA==seedling
     pause(2);
     fprintf(app.arduino3,'%c','S');%we can use this to turn on air 
     spraystarted=toc
%      pause(2);
%      pasostarter=fscanf(app.arduino3,'%d')
 end 
 
 if toc>=0 && airstarted==0
     pause(2);
     fprintf(app.arduino3,'%c','A');%we can use this to turn on air 
     airstarted=toc
    
     
 end
 if toc>=tiempocat(1,1) && ethylenestarted==0 && seedling==2
     pause(2);
     fprintf(app.arduino3,'%c','E');%we can use this to turn on ethylene
     ethylenestarted=toc
 end

 if toc>=(tiempocat(1,1)+tiempocat(1,2))&& recoverystarted==0 && seedling==2
      pause(2);
     fprintf(app.arduino3,'%c','A');%we can use this to turn on air
     recoverystarted=toc

 end
 if toc>=(tiempocat(1,1)+tiempocat(1,2)+tiempocat(1,3)) && recoveryended==0 && seedling==1
     pause(2);
     fprintf(app.arduino3,'%c','N');%we can use this to turn on air
     recoveryended=toc
 end


%pause(1); 
    fprintf(app.arduino,'%c','R');%we can use this for the first arduino/camera
%pause(1);
    seedlingnumber=fscanf(app.arduino,'%d');
if seedlingnumber<1000
if seedlingnumber>=seedling
    seedling=seedling+1;
else
    seedlingsinA=seedling;
    seedling=1;
    frame=frame+1;
end


vid1.LoggingMode = 'memory';
vid1.FramesPerTrigger = 1;
start(vid1);
data=getdata(vid1);
seedlingstr=num2str(seedling);
framestr=num2str(frame);
camera1='CA';
nombre=strcat(app.name,camera1,'.',seedlingstr,'.',framestr,'.tif');
imwrite(data,nombre);
imshow(data,'Parent',app.UIAxes_3);

stop(vid1);

app.TimeTextArea.Value=num2str(floor(toc/60));
app.SeedlingTextArea.Value=seedlingstr;
app.CameraTextArea.Value=camera1;
app.FrameTextArea.Value=framestr;
drawnow;
else
end


%pause(1);
fprintf(app.arduino2,'%c','R');%we can use this for the second arduino/camera
%pause(1);
seedlingnumber2=fscanf(app.arduino2,'%d');

if seedlingnumber2<1000
if seedlingnumber2>=seedling2
    seedling2=seedling2+1;
else
    seedlingsinB=seedling2;
    seedling2=1;
    frame2=frame2+1;
end

vid2.LoggingMode = 'memory';
vid2.FramesPerTrigger = 1;
start(vid2);
data=getdata(vid2);
seedlingstr2=num2str(seedling2);
framestr2=num2str(frame2);
camera2='CB';
nombre=strcat(app.name,camera2,'.',seedlingstr2,'.',framestr2,'.tif');
imwrite(data,nombre);
imshow(data,'Parent',app.UIAxes_3);

stop(vid2);
app.TimeTextArea.Value=num2str(floor(toc/60));
app.SeedlingTextArea.Value=seedlingstr2;
app.CameraTextArea.Value=camera2;
app.FrameTextArea.Value=framestr2;
drawnow;
else
end

end
            
        end

        % Value changed function: DropDownSectionsHY
        function DropDownSectionsHYValueChanged(app, event)
value = app.DropDownSectionsHY.Value;
% Set current data to the selected data set.
switch value
case '1' % User selects peaks.
   app.segmentshy = 1;
case '2' % User selects membrane.
   app.segmentshy = 2;
case '3' % User selects sinc.
   app.segmentshy = 3;
case '4' % User selects sinc.
   app.segmentshy = 4;  
end

        end

        % Value changed function: DropDownPointsHY
        function DropDownPointsHYValueChanged(app, event)
            value = app.DropDownPointsHY.Value;
switch value
case '10' % User selects peaks.
   app.numpunthy = 10;
case '12' % User selects membrane.
   app.numpunthy = 12;
case '14' % User selects sinc.
   app.numpunthy = 14;
case '16' % User selects sinc.
   app.numpunthy = 16;  
case '18' % User selects sinc.
   app.numpunthy = 18;
case '20' % User selects sinc.
   app.numpunthy = 20; 
case '22' % User selects peaks.
   app.numpunthy = 22;
case '24' % User selects membrane.
   app.numpunthy = 24;
case '26' % User selects sinc.
   app.numpunthy = 26;
case '28' % User selects sinc.
   app.numpunthy = 28;  
case '30' % User selects sinc.
   app.numpunthy = 30;
case '40' % User selects sinc.
   app.numpunthy = 40; 
case '2' % User selects sinc.
   app.numpunthy = 2; 
end

        end

        % Value changed function: DropDownSetsHY
        function DropDownSetsHYValueChanged(app, event)
            value = app.DropDownSetsHY.Value;
% Set current data to the selected data set.
switch value
case '1' % User selects peaks.
   app.setshy = 1;
case '2' % User selects membrane.
   app.setshy = 2;
case '3' % User selects sinc.
   app.setshy = 3;
case '4' % User selects sinc.
   app.setshy = 4;  
case '5' % User selects sinc.
   app.setshy = 5;
case '6' % User selects sinc.
   app.setshy = 6; 
case '7' % User selects peaks.
   app.setshy = 7;
case '8' % User selects membrane.
   app.setshy = 8;
case '9' % User selects sinc.
   app.setshy = 9;
case '10' % User selects sinc.
   app.setshy = 10;  
end
            
        end

        % Value changed function: SwitchFRHY
        function SwitchFRHYValueChanged(app, event)
            value = app.SwitchFRHY.Value;
            if value == "Forward"
            app.reversehy=0;
            else
            app.reversehy=1;
            end
        end

        % Value changed function: SwitchVisHY
        function SwitchVisHYValueChanged(app, event)
            value = app.SwitchVisHY.Value;
if value == "Yes"
    app.visualhy=1;
else
    app.visualhy=0;
end            
        end

        % Value changed function: SwitchAngleHY
        function SwitchAngleHYValueChanged(app, event)
            value = app.SwitchAngleHY.Value;
            if value == "No"
                 app.angulacionhy=0;
            else
                 app.angulacionhy=1;
            end
        end

        % Value changed function: SwitchMovHY
        function SwitchMovHYValueChanged(app, event)
            value = app.SwitchMovHY.Value;
                if value == "Yes"
                    app.savehy=1;
                else
                    app.savehy=0;
                end
        end

        % Button pushed function: SelectlastframetoanalyzeButton_2
        function SelectlastframetoanalyzeButton_2Pushed(app, event)
            
prompt = {'Enter last image to analyze: '};
title = 'Input';
dims = [1 35];
x1 = inputdlg(prompt,title,dims);
app.xhy=str2num(x1{1});
app.UIFigure.Visible='off';
app.UIFigure.Visible='on';
        end

        % Button pushed function: SelectfirstimageButton_2
        function SelectfirstimageButton_2Pushed(app, event)

if app.angulacionhy==1
    app.numpunthy=2;
    app.setshy=2;
end
cy=1;

app.contadorhy=app.contadorhy+1;
if app.segmentshy == 1 
    ciclos=1;
end
if app.segmentshy == 2
    ciclos=2;
end
if app.segmentshy == 3
    ciclos=3;
end
if app.segmentshy == 4
    ciclos=4;
end

if app.reversehy == 1
    ciclos=ciclos+1;
end

while cy<=ciclos
text=sprintf('Selected segment #: %d out of: %d.',cy,ciclos);
        app.EditFieldMessage.Value=text; 
[filename, pathname] = uigetfile({'*.tif';'*.jpg';'*.jpeg'},'File Selector');
app.pathnamecathy{app.contadorhy}=pathname;
names=strcat(pathname,'*','.tif');
if contains(filename,'jpeg')
    names=strcat(pathname,'*','.jpeg');
end
app.namescathy{app.contadorhy}=names;
name = strcat(pathname, filename);
app.namecathy{app.contadorhy}=name;
I=imread(name);
imshow(I,'Parent',app.UIAxes_2);%display image

prompt = {'Do you want to select this image?: '};
title = 'Input';
dims = [1 35];
definput = {'Y'};
x = inputdlg(prompt,title,dims,definput);

if strcmp(x,'n') || (strcmp(x,'N'))
    
    continue;
end

originalsplit=strsplit(pathname,filesep);
genotype=originalsplit(1,end-2);
%genotyperoot=originalsplit(1,end-3);
%genotyperoot=char(genotyperoot);
genotype=char(genotype);
app.listofgenotypeshy{end+1}=genotype;
cytxt=num2str(cy);
app.listofgenotypesroothy{end+1}=strcat(genotype,'-',cytxt,';');
splitfilename=strsplit(filename,'.');
filenumber=splitfilename{end-1};
if numel(filenumber)>4
    filenumber=filenumber(end-3:end);
end

    
app.filenumbercathy{app.contadorhy,cy}=filenumber;

app.ListoffilesTextArea_3.Value=cell2mat(app.listofgenotypesroothy);
%% select area to use as control
app.EditFieldMessage.Value='Select area containing the object to track';
figure;
imshow(I);
objectRegion=round(getPosition(imrect));
app.objectRegioncathy{app.contadorhy,cy}=objectRegion;
app.EditFieldMessage.Value='Press any key when ready';
pause;
% close(gcf);
% %% select area you want to trackapp.listofgenotypesroothy
% set(handles.Mesage,'String','Select area you want to track');
% objectRegion1=round(getPosition(imrect));
% objectRegioncat1{contador,cy}=objectRegion1;
% set(handles.Mesage,'String','Press any key when ready');
% pause;
for bx=1:app.setshy
text=sprintf('Select %d points along the hypocotyl for set %d',app.numpunthy,bx);
app.EditFieldMessage.Value=text; 
% figure;
% imshow(I);
for cx=1:app.numpunthy
objectPoint=round(getPosition(impoint));
app.objectPointcathy=cat(1,app.objectPointcathy,objectPoint);
drawnow;
end
close(gcf);
app.objectPointscathy{app.contadorhy,cy,bx}=app.objectPointcathy;
app.objectPointcathy=[];
if bx<app.setshy
 text=sprintf('Press any key when ready to select %d points for set %d',app.numpunthy,bx+1);
app.EditFieldMessage.Value=text;
pause
    
end
end
cy=cy+1;
end
%%send handles
if app.reversehy==1
    ciclos=ciclos-1;
end

app.cicloscathy(1,app.contadorhy)=ciclos;

% app.numpunthy=num2str(app.numpunthy);


app.EditFieldMessage.Value='If you want to add another image click "Select First Image". If done, click "Run"';

        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
app.EditFieldMessage.Value='Creating movie .avi files. This can take a while';

numberofframes={};

%% loop for each seedling creating a movie AVI

for seedlings=1:app.contadorhy
Files=dir(app.namescathy{seedlings}); %all files in a directory
N=app.natsortfiles({Files.name});
destino=strcat(app.pathnamecathy{seedlings},'Video.avi');%destination for the Video file
%numberofframes{seedlings}=length(Files);
numberofframes{seedlings}=0;
writerObj = VideoWriter(destino);% Create a video writer objec
%writerObj.FrameRate = 10;% Set frame rate
open(writerObj) % Open video writer object and write frames sequentially
if app.xhy==0
for i = 1:length(Files) %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescathy{seedlings},'jpeg')
        W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecathy{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
      
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
    

     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
    
     writeVideo(writerObj, input); % Write frame now 
     
      numberofframes{seedlings}=numberofframes{seedlings}+1;
      
 %     if i==length(Files)
 %       writeVideo(writerObj, input); % Write frame now  
  %    end
     end
end
else
    for i = 1:app.xhy %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescathy{seedlings},'jpeg')
        W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecathy{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
%      
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end

    text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
    
     writeVideo(writerObj, input); % Write frame now 
     
      numberofframes{seedlings}=numberofframes{seedlings}+1;
      
 %     if i==length(Files)
 %       writeVideo(writerObj, input); % Write frame now  
  %    end
     end
    end
    

end

% Close the video writer object
close(writerObj);
end


%%loop to create a reverse video is this option is selected
if app.reversehy==1
for seedlings=1:app.contadorhy
Files=dir(app.namescathy{seedlings}); %all files in a directory
N=app.natsortfiles({Files.name});
destino0=strcat(app.pathnamecathy{seedlings},'Videorev.avi');%destination for the Video file
%numberofframes{seedlings}=length(Files);


writerObj = VideoWriter(destino0);% Create a video writer objec
%writerObj.FrameRate = 10;% Set frame rate
open(writerObj) % Open video writer object and write frames sequentially
if app.xhy==0
for i = length(Files):-1:1 %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescathy{seedlings},'jpeg')
        W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecathy{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
     writeVideo(writerObj, input); % Write frame now 


     end
end

else
    for i = app.xhy:-1:1 %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescathy{seedlings},'jpeg')
        W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecathy{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
     writeVideo(writerObj, input); % Write frame now 


     end
     end
end

% Close the video writer object
close(writerObj);
end 
    
end


%% Track a Face in Scene

%%start loop for each seedling
for seedlings=1:app.contadorhy

text=sprintf('Analyzing image: %s',app.listofgenotypeshy{seedlings});
     app.EditFieldMessage.Value=text;  
     drawnow;
%% 
% Create System objects for reading and displaying video and for drawing a bounding box of the object. 

timefilename=strcat(app.pathnamecathy{seedlings},'Video.avi');
videoFileReader = vision.VideoFileReader(timefilename);
videoPlayer = vision.VideoPlayer('Position',[100,100,680,520]); %
videoPlayer1 = vision.VideoPlayer('Position',[1000,1000,680,520]); %
%% 
% Read the first video frame, which contains the object, define the region.
%%%%%%%%%objectFrame = step(videoFileReader);

if app.reversehy==1
    % Create System objects for reading and displaying video and for drawing a bounding box of the object. 

timefilenamerev=strcat(app.pathnamecathy{seedlings},'Videorev.avi');
videoFileReaderrev = vision.VideoFileReader(timefilenamerev);
videoPlayerrev = vision.VideoPlayer('Position',[100,100,680,520]); %
videoPlayer1rev = vision.VideoPlayer('Position',[1000,1000,680,520]); %
%% 
% Read the first video frame, which contains the object, define the region.
%%%%%%%%%%%%%objectFramerev = step(videoFileReaderrev);
end

% Show initial frame with a red bounding box.
%objectImage = insertShape(objectFrame,'Rectangle',objectRegioncat{seedlings,cy},'Color','red'); 
%objectImage1 = insertShape(objectFrame,'Rectangle',objectRegioncat1{seedlingscy},'Color','red'); 

%figure;
%subplot(1,2,1);
%imshow(objectImage);
%subplot(1,2,2);
%imshow(objectImage1);
%title('Red box shows object region');
%% 
% Detect interest points in the object region.
%points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat{seedlings});
%points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat1{seedlings});
%% 
% Display the detected points.
%pointImage = insertMarker(objectFrame,points.Location,'+','Color','white');
%pointImage1 = insertMarker(objectFrame,points1.Location,'+','Color','white');
%figure;
%subplot(1,2,1);
%imshow(pointImage);
%subplot(1,2,2);
%imshow(pointImage1);
%title('Detected interest points');
%% 
% Create a tracker object.
%tracker = vision.PointTracker('MaxBidirectionalError',1);
%tracker1 = vision.PointTracker('MaxBidirectionalError',1);
%% 
% Initialize the tracker.
%initialize(tracker,points.Location,objectFrame);
%initialize(tracker1,points1.Location,objectFrame);
%% 
% Read, track, display points, and results in each video frame.
puntosx=[];
puntosy=[];
puntosxrev=[];
puntosyrev=[];
entro=0;

puntosx1=[];
puntosy1=[];
puntosx1rev=[];
puntosy1rev=[];

%growth1=[];
%growthT1=[];
contador10=1;

destino2=strcat(app.pathnamecathy{seedlings},'VideoTracker.avi');%destination for the Video file


writerObj1 = VideoWriter(destino2);% Create a video writer objec
writerObj1.FrameRate = 10;% Set frame rate
open(writerObj1) % Open video writer object and write frames sequentially
if app.cicloscathy(seedlings)==1
    start1=str2num(app.filenumbercathy{seedlings,1})+1;
    start2=start1;
    start3=start1;
    start4=start1;
    end1=numberofframes{seedlings}+1;
    end2=end1;
    end3=end1;
    end4=end1;
    puntosxcat11=[];puntosycat11=[];puntosx1cat11=[];puntosy1cat11=[];
    if app.reversehy==1
        end1=(str2num(app.filenumbercathy{seedlings,2})+2); 
        end2=end1;
        end3=end1;
        end4=end1;
    end
    
end

if app.cicloscathy(seedlings)==2
    start1=str2num(app.filenumbercathy{seedlings,1})+1;
    start2=str2num(app.filenumbercathy{seedlings,2})+1;
    start3=start2;
    start4=start2;
    end1=str2num(app.filenumbercathy{seedlings,2})+1;
    end2=numberofframes{seedlings}+1;
    end3=end2;
    end4=end2;
    puntosxcat21=[];puntosycat21=[];puntosx1cat21=[];puntosy1cat21=[];
    puntosxcat22=[];puntosycat22=[];puntosx1cat22=[];puntosy1cat22=[];
    if app.reversehy==1
    end2=(str2num(app.filenumbercathy{seedlings,3})+2); 
    end3=end2;
    end4=end2;
    end
end

if app.cicloscathy(seedlings)==3
    start1=str2num(app.filenumbercathy{seedlings,1})+1;
    start2=str2num(app.filenumbercathy{seedlings,2})+1;
    start3=str2num(app.filenumbercathy{seedlings,3})+1;
    start4=start3;
    end1=str2num(app.filenumbercathy{seedlings,2})+1;
    end2=str2num(app.filenumbercathy{seedlings,3})+1;  
    end3=numberofframes{seedlings}+1;
    end4=end3;
    puntosxcat31=[];puntosycat31=[];puntosx1cat31=[];puntosy1cat31=[];
    puntosxcat32=[];puntosycat32=[];puntosx1cat32=[];puntosy1cat32=[];
    puntosxcat33=[];puntosycat33=[];puntosx1cat33=[];puntosy1cat33=[];
    if app.reversehy==1
    end3=(str2num(app.filenumbercathy{seedlings,4})+2);
    end4=end3;
    end
end


if app.cicloscathy(seedlings)==4
    start1=str2num(app.filenumbercathy{seedlings,1})+1;
    start2=str2num(app.filenumbercathy{seedlings,2})+1;
    start3=str2num(app.filenumbercathy{seedlings,3})+1;
    start4=str2num(app.filenumbercathy{seedlings,4})+1;
    end1=str2num(app.filenumbercathy{seedlings,2})+1;
    end2=str2num(app.filenumbercathy{seedlings,3})+1;
    end3=str2num(app.filenumbercathy{seedlings,4})+1;
    end4=numberofframes{seedlings}+1;
    puntosxcat41=[];puntosycat41=[];puntosx1cat41=[];puntosy1cat41=[];
    puntosxcat42=[];puntosycat42=[];puntosx1cat42=[];puntosy1cat42=[];
    puntosxcat43=[];puntosycat43=[];puntosx1cat43=[];puntosy1cat43=[];
    puntosxcat44=[];puntosycat44=[];puntosx1cat44=[];puntosy1cat44=[];
    if app.reversehy==1
    end4=(str2num(app.filenumbercathy{seedlings,5})+2);  
    end
end

while ~isDone(videoFileReader)
         
if (app.cicloscathy(seedlings)==1) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = step(videoFileReader);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,1});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat1{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat11=cat(2,puntosxcat11,points(:,1));
      puntosycat11=cat(2,puntosycat11,points(:,2));   
        
%       puntosx1cat11=cat(2,puntosx1cat11,points1(:,1));
%       puntosy1cat11=cat(2,puntosy1cat11,points1(:,2));     
if app.savehy==1      
      writeVideo(writerObj1, out);
end


       end

      if (app.cicloscathy(seedlings)==1) & (contador10<=end1) & (contador10>start1)
        
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat11=cat(2,puntosxcat11,points(:,1));
      puntosycat11=cat(2,puntosycat11,points(:,2));   
        
%       puntosx1cat11=cat(2,puntosx1cat11,points1(:,1));
%       puntosy1cat11=cat(2,puntosy1cat11,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      
      end


       if (app.cicloscathy(seedlings)==2) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
      objectFrame = step(videoFileReader);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,1});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat1{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
%       
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat21=cat(2,puntosxcat21,points(:,1));
      puntosycat21=cat(2,puntosycat21,points(:,2));   
        
%       puntosx1cat21=cat(2,puntosx1cat21,points1(:,1));
%       puntosy1cat21=cat(2,puntosy1cat21,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end      
       end
      
      if (app.cicloscathy(seedlings)==2) & (contador10>start1) & (contador10<=end1)
 

      frame = step(videoFileReader);
      contador10=contador10+1;
      
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat21=cat(2,puntosxcat21,points(:,1));
      puntosycat21=cat(2,puntosycat21,points(:,2));   
        
%       puntosx1cat21=cat(2,puntosx1cat21,points1(:,1));
%       puntosy1cat21=cat(2,puntosy1cat21,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end     


      end
      

       if (app.cicloscathy(seedlings)==2) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);  
      
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat22=cat(2,puntosxcat22,points(:,1));
      puntosycat22=cat(2,puntosycat22,points(:,2));   
        
%       puntosx1cat22=cat(2,puntosx1cat22,points1(:,1));
%       puntosy1cat22=cat(2,puntosy1cat22,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end      


       end
       
       if app.cicloscathy(seedlings)==2 & contador10>=end1 & contador10<=end2
      
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat22=cat(2,puntosxcat22,points(:,1));
      puntosycat22=cat(2,puntosycat22,points(:,2));   
        
%       puntosx1cat22=cat(2,puntosx1cat22,points1(:,1));
%       puntosy1cat22=cat(2,puntosy1cat22,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end


       if (app.cicloscathy(seedlings)==3) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
         objectFrame = step(videoFileReader);
         contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,1});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
     
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat31=cat(2,puntosxcat31,points(:,1));
      puntosycat31=cat(2,puntosycat31,points(:,2));   
        
%       puntosx1cat31=cat(2,puntosx1cat31,points1(:,1));
%       puntosy1cat31=cat(2,puntosy1cat31,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end      
      
      
       end
      
      if app.cicloscathy(seedlings)==3 & contador10>start1 & contador10<=end1
      
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat31=cat(2,puntosxcat31,points(:,1));
      puntosycat31=cat(2,puntosycat31,points(:,2));   
        
%       puntosx1cat31=cat(2,puntosx1cat31,points1(:,1));
%       puntosy1cat31=cat(2,puntosy1cat31,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
     end
      
      
      if (app.cicloscathy(seedlings)==3) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
%       
       [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat32=cat(2,puntosxcat32,points(:,1));
      puntosycat32=cat(2,puntosycat32,points(:,2));   
        
%       puntosx1cat32=cat(2,puntosx1cat32,points1(:,1));
%       puntosy1cat32=cat(2,puntosy1cat32,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      
       end
      if app.cicloscathy(seedlings)==3 & contador10>=end1 & contador10<=end2

       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat32=cat(2,puntosxcat32,points(:,1));
      puntosycat32=cat(2,puntosycat32,points(:,2));   
        
%       puntosx1cat32=cat(2,puntosx1cat32,points1(:,1));
%       puntosy1cat32=cat(2,puntosy1cat32,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end
      
      
      if (app.cicloscathy(seedlings)==3) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
        
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,3});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
%       
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);


%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat33=cat(2,puntosxcat33,points(:,1));
      puntosycat33=cat(2,puntosycat33,points(:,2));   
        
%       puntosx1cat33=cat(2,puntosx1cat33,points1(:,1));
%       puntosy1cat33=cat(2,puntosy1cat33,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      
      
       end
      if app.cicloscathy(seedlings)==3 & contador10>=end2 & contador10<=end3
      
          frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%           
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat33=cat(2,puntosxcat33,points(:,1));
      puntosycat33=cat(2,puntosycat33,points(:,2));   
        
%       puntosx1cat33=cat(2,puntosx1cat33,points1(:,1));
%       puntosy1cat33=cat(2,puntosy1cat33,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end


      if (app.cicloscathy(seedlings)==4) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = step(videoFileReader);
        contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,1});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat41=cat(2,puntosxcat41,points(:,1));
      puntosycat41=cat(2,puntosycat41,points(:,2));   
        
%       puntosx1cat41=cat(2,puntosx1cat41,points1(:,1));
%       puntosy1cat41=cat(2,puntosy1cat41,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end


       end
      if app.cicloscathy(seedlings)==4 & contador10>start1 & contador10<=end1
       frame = step(videoFileReader);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat41=cat(2,puntosxcat41,points(:,1));
      puntosycat41=cat(2,puntosycat41,points(:,2));   
        
%       puntosx1cat41=cat(2,puntosx1cat41,points1(:,1));
%       puntosy1cat41=cat(2,puntosy1cat41,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end


      if (app.cicloscathy(seedlings)==4) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat42=cat(2,puntosxcat42,points(:,1));
      puntosycat42=cat(2,puntosycat42,points(:,2));   
        
%       puntosx1cat42=cat(2,puntosx1cat42,points1(:,1));
%       puntosy1cat42=cat(2,puntosy1cat42,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      
      end
      if app.cicloscathy(seedlings)==4 & contador10>=end1 & contador10<=end2
       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat42=cat(2,puntosxcat42,points(:,1));
      puntosycat42=cat(2,puntosycat42,points(:,2));   
        
%       puntosx1cat42=cat(2,puntosx1cat42,points1(:,1));
%       puntosy1cat42=cat(2,puntosy1cat42,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end
      
      if (app.cicloscathy(seedlings)==4) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,3});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat43=cat(2,puntosxcat43,points(:,1));
      puntosycat43=cat(2,puntosycat43,points(:,2));   
        
%       puntosx1cat43=cat(2,puntosx1cat43,points1(:,1));
%       puntosy1cat43=cat(2,puntosy1cat43,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      
       end
      
      if app.cicloscathy(seedlings)==4 & contador10>=end2 & contador10<=end3
       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat43=cat(2,puntosxcat43,points(:,1));
      puntosycat43=cat(2,puntosycat43,points(:,2));   
        
%       puntosx1cat43=cat(2,puntosx1cat43,points1(:,1));
%       puntosy1cat43=cat(2,puntosy1cat43,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end
      
      
      if (app.cicloscathy(seedlings)==4) & (contador10==end3)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,4});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcat44=cat(2,puntosxcat44,points(:,1));
      puntosycat44=cat(2,puntosycat44,points(:,2));   
        
%       puntosx1cat44=cat(2,puntosx1cat44,points1(:,1));
%       puntosy1cat44=cat(2,puntosy1cat44,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end


       end
      if app.cicloscathy(seedlings)==4 & contador10>=end3 & contador10<=end4
       frame = step(videoFileReader);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcat44=cat(2,puntosxcat44,points(:,1));
      puntosycat44=cat(2,puntosycat44,points(:,2));   
        
%       puntosx1cat44=cat(2,puntosx1cat44,points1(:,1));
%       puntosy1cat44=cat(2,puntosy1cat44,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1, out);
end
      end


%if contador10==numberofframes{seedlings}
   % break
%end


end
%% 
% Release the video reader and player.
release(videoPlayer);
release(videoPlayer1);
release(videoFileReader);
close(writerObj1);


%%for the reverse video
if app.reversehy ==1


destino3=strcat(app.pathnamecathy{seedlings},'VideoTrackerReverse.avi');%destination for the Video file
contador10=1;    
    
writerObj1rev = VideoWriter(destino3);% Create a video writer objec
writerObj1rev.FrameRate = 10;% Set frame rate
open(writerObj1rev) % Open video writer object and write frames sequentially

if app.cicloscathy(seedlings)==1
    start1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,2});
    end1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,1})+1;
    end2=end1;
    end3=end1;
    end4=end1;
    puntosxcatrev11=[];puntosycatrev11=[];puntosx1catrev11=[];puntosy1catrev11=[];
end

if app.cicloscathy(seedlings)==2
    start1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,3});
    end1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,2})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,1})+1;
    end3=end2;
    end4=end2;
    puntosxcatrev21=[];puntosycatrev21=[];puntosx1catrev21=[];puntosy1catrev21=[];
    puntosxcatrev22=[];puntosycatrev22=[];puntosx1catrev22=[];puntosy1catrev22=[];
end

if app.cicloscathy(seedlings)==3
    start1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,4});
    end1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,3})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,2})+1;  
    end3=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,1})+1;
    end4=end3;
    puntosxcatrev31=[];puntosycatrev31=[];puntosx1catrev31=[];puntosy1catrev31=[];
    puntosxcatrev32=[];puntosycatrev32=[];puntosx1catrev32=[];puntosy1catrev32=[];
    puntosxcatrev33=[];puntosycatrev33=[];puntosx1catrev33=[];puntosy1catrev33=[];
end


if app.cicloscathy(seedlings)==4
    start1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,5});
    
    end1=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,4})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,3})+1;
    end3=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,2})+1;
    end4=numberofframes{seedlings}-str2num(app.filenumbercathy{seedlings,1})+1;
    puntosxcatrev41=[];puntosycatrev41=[];puntosx1catrev41=[];puntosy1catrev41=[];
    puntosxcatrev42=[];puntosycatrev42=[];puntosx1catrev42=[];puntosy1catrev42=[];
    puntosxcatrev43=[];puntosycatrev43=[];puntosx1catrev43=[];puntosy1catrev43=[];
    puntosxcatrev44=[];puntosycatrev44=[];puntosx1catrev44=[];puntosy1catrev44=[];
end


while ~isDone(videoFileReaderrev)


       if (app.cicloscathy(seedlings)==1) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = step(videoFileReaderrev);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev11=cat(2,puntosxcatrev11,points(:,1));
      puntosycatrev11=cat(2,puntosycatrev11,points(:,2));   
        
%       puntosx1catrev11=cat(2,puntosx1catrev11,points1(:,1));
%       puntosy1catrev11=cat(2,puntosy1catrev11,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
  
       end

      if (app.cicloscathy(seedlings)==1) & (contador10<=end1) & (contador10>start1)
        
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev11=cat(2,puntosxcatrev11,points(:,1));
      puntosycatrev11=cat(2,puntosycatrev11,points(:,2));   
        
%       puntosx1catrev11=cat(2,puntosx1catrev11,points1(:,1));
%       puntosy1catrev11=cat(2,puntosy1catrev11,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end


       if (app.cicloscathy(seedlings)==2) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
      objectFrame = step(videoFileReaderrev);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,3});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev21=cat(2,puntosxcatrev21,points(:,1));
      puntosycatrev21=cat(2,puntosycatrev21,points(:,2));   
        
%       puntosx1catrev21=cat(2,puntosx1catrev21,points1(:,1));
%       puntosy1catrev21=cat(2,puntosy1catrev21,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      
       end
      
      if (app.cicloscathy(seedlings)==2) & (contador10>start1) & (contador10<=end1)
 

      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev21=cat(2,puntosxcatrev21,points(:,1));
      puntosycatrev21=cat(2,puntosycatrev21,points(:,2));   
        
%       puntosx1catrev21=cat(2,puntosx1catrev21,points1(:,1));
%       puntosy1catrev21=cat(2,puntosy1catrev21,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end


      end
      

       if (app.cicloscathy(seedlings)==2) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);  
      
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev22=cat(2,puntosxcatrev22,points(:,1));
      puntosycatrev22=cat(2,puntosycatrev22,points(:,2));   
        
%       puntosx1catrev22=cat(2,puntosx1catrev22,points1(:,1));
%       puntosy1catrev22=cat(2,puntosy1catrev22,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end


       end
       
       if app.cicloscathy(seedlings)==2 & contador10>=end1 & contador10<=end2
      
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev22=cat(2,puntosxcatrev22,points(:,1));
      puntosycatrev22=cat(2,puntosycatrev22,points(:,2));   
        
%       puntosx1catrev22=cat(2,puntosx1catrev22,points1(:,1));
%       puntosy1catrev22=cat(2,puntosy1catrev22,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end


       if (app.cicloscathy(seedlings)==3) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
         objectFrame = step(videoFileReaderrev);
         contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,4});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev31=cat(2,puntosxcatrev31,points(:,1));
      puntosycatrev31=cat(2,puntosycatrev31,points(:,2));   
%         
%       puntosx1catrev31=cat(2,puntosx1catrev31,points1(:,1));
%       puntosy1catrev31=cat(2,puntosy1catrev31,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end


       end
      
      if app.cicloscathy(seedlings)==3 & contador10>start1 & contador10<=end1
      
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%      
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev31=cat(2,puntosxcatrev31,points(:,1));
      puntosycatrev31=cat(2,puntosycatrev31,points(:,2));   
        
%       puntosx1catrev31=cat(2,puntosx1catrev31,points1(:,1));
%       puntosy1catrev31=cat(2,puntosy1catrev31,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end
      
      
      if (app.cicloscathy(seedlings)==3) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,3});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
       [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev32=cat(2,puntosxcatrev32,points(:,1));
      puntosycatrev32=cat(2,puntosycatrev32,points(:,2));   
%         
%       puntosx1catrev32=cat(2,puntosx1catrev32,points1(:,1));
%       puntosy1catrev32=cat(2,puntosy1catrev32,points1(:,2));     
%       
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      
       end
      if app.cicloscathy(seedlings)==3 & contador10>=end1 & contador10<=end2

       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev32=cat(2,puntosxcatrev32,points(:,1));
      puntosycatrev32=cat(2,puntosycatrev32,points(:,2));   
        
%       puntosx1catrev32=cat(2,puntosx1catrev32,points1(:,1));
%       puntosy1catrev32=cat(2,puntosy1catrev32,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end
      
      
      if (app.cicloscathy(seedlings)==3) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
        
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%           
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev33=cat(2,puntosxcatrev33,points(:,1));
      puntosycatrev33=cat(2,puntosycatrev33,points(:,2));   
        
%       puntosx1catrev33=cat(2,puntosx1catrev33,points1(:,1));
%       puntosy1catrev33=cat(2,puntosy1catrev33,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      
       end
      if app.cicloscathy(seedlings)==3 & contador10>=end2 & contador10<=end3
      
          frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%           
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev33=cat(2,puntosxcatrev33,points(:,1));
      puntosycatrev33=cat(2,puntosycatrev33,points(:,2));   
        
%       puntosx1catrev33=cat(2,puntosx1catrev33,points1(:,1));
%       puntosy1catrev33=cat(2,puntosy1catrev33,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end


      if (app.cicloscathy(seedlings)==4) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = step(videoFileReaderrev);
        contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,5});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,5});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
%      
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev41=cat(2,puntosxcatrev41,points(:,1));
      puntosycatrev41=cat(2,puntosycatrev41,points(:,2));   
        
%       puntosx1catrev41=cat(2,puntosx1catrev41,points1(:,1));
%       puntosy1catrev41=cat(2,puntosy1catrev41,points1(:,2));     
%       
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end


       end
      if app.cicloscathy(seedlings)==4 & contador10>start1 & contador10<=end1
       frame = step(videoFileReaderrev);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);

      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev41=cat(2,puntosxcatrev41,points(:,1));
      puntosycatrev41=cat(2,puntosycatrev41,points(:,2));   
        
%       puntosx1catrev41=cat(2,puntosx1catrev41,points1(:,1));
%       puntosy1catrev41=cat(2,puntosy1catrev41,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end


      if (app.cicloscathy(seedlings)==4) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,4});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
%       
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev42=cat(2,puntosxcatrev42,points(:,1));
      puntosycatrev42=cat(2,puntosycatrev42,points(:,2));   
        
%       puntosx1catrev42=cat(2,puntosx1catrev42,points1(:,1));
%       puntosy1catrev42=cat(2,puntosy1catrev42,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      
      end
      if app.cicloscathy(seedlings)==4 & contador10>=end1 & contador10<=end2
       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev42=cat(2,puntosxcatrev42,points(:,1));
      puntosycatrev42=cat(2,puntosycatrev42,points(:,2));   
        
%       puntosx1catrev42=cat(2,puntosx1catrev42,points1(:,1));
%       puntosy1catrev42=cat(2,puntosy1catrev42,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end
      
      if (app.cicloscathy(seedlings)==4) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,3});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev43=cat(2,puntosxcatrev43,points(:,1));
      puntosycatrev43=cat(2,puntosycatrev43,points(:,2));   
        
%       puntosx1catrev43=cat(2,puntosx1catrev43,points1(:,1));
%       puntosy1catrev43=cat(2,puntosy1catrev43,points1(:,2));     
%       
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      
       end
      
      if app.cicloscathy(seedlings)==4 & contador10>=end2 & contador10<=end3
       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev43=cat(2,puntosxcatrev43,points(:,1));
      puntosycatrev43=cat(2,puntosycatrev43,points(:,2));   
        
%       puntosx1catrev43=cat(2,puntosx1catrev43,points1(:,1));
%       puntosy1catrev43=cat(2,puntosy1catrev43,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end
      
      
      if (app.cicloscathy(seedlings)==4) & (contador10==end3)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy{seedlings,2});
%       points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncathy1{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
%       tracker1 = vision.PointTracker('MaxBidirectionalError',1);
      initialize(tracker,points.Location,objectFrame);
%       initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
%       [points1, validity1] = step(tracker1,objectFrame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points(validity, :),'+');
      
      puntosxcatrev44=cat(2,puntosxcatrev44,points(:,1));
      puntosycatrev44=cat(2,puntosycatrev44,points(:,2));   
        
%       puntosx1catrev44=cat(2,puntosx1catrev44,points1(:,1));
%       puntosy1catrev44=cat(2,puntosy1catrev44,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end


       end
      if app.cicloscathy(seedlings)==4 & contador10>=end3 & contador10<=end4
       frame = step(videoFileReaderrev);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
%       [points1, validity1] = step(tracker1,frame);
%       
%       points3 = cat(1,points,points1);
%       validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points(validity, :),'+');
      
      puntosxcatrev44=cat(2,puntosxcatrev44,points(:,1));
      puntosycatrev44=cat(2,puntosycatrev44,points(:,2));   
        
%       puntosx1catrev44=cat(2,puntosx1catrev44,points1(:,1));
%       puntosy1catrev44=cat(2,puntosy1catrev44,points1(:,2));     
      
if app.savehy==1      
      writeVideo(writerObj1rev, out);
end
      end
      
   if contador10<start1
        framex = step(videoFileReaderrev);
       contador10=contador10+1;
   end
   
 if app.cicloscathy(seedlings)==1 & contador10>end1 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end
  
 if app.cicloscathy(seedlings)==2 & contador10>end2 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end    
 
 if app.cicloscathy(seedlings)==3 & contador10>end3 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end
  
 if app.cicloscathy(seedlings)==4 & contador10>end4 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end


%if contador10==numberofframes{seedlings}
%    break
%end
end
%% 
% Release the video reader and player.
release(videoPlayerrev);
release(videoPlayer1rev);
release(videoFileReaderrev);
close(writerObj1rev);


end


resultadosC={};
resultadosmixC={};
resultadosreverseC={};
resultadosfinal=[];
patternC={};
patternmixC={};
anglemixC={};
anglemixreverseC={};

anguloC={};

if (app.cicloscathy(seedlings)==1)
    for xc=1:app.setshy
  [results,angle,pattern]=app.growthcalculatorhy(puntosxcat11,puntosycat11,app.objectPointscathy{seedlings,1,xc},timefilename,start1,end1,app.visualhy);
  patternC{1,app.setshy}=pattern;
  if app.reversehy==1
      [resultsrev,anglerev,patternrev]=app.growthcalculatorhy(puntosxcatrev11,puntosycatrev11,app.objectPointscathy{seedlings,2,xc},timefilenamerev,start1,end1,app.visualhy);
      resultsrev=flipud(resultsrev);
      resultsmix=results;
      resultsmix=(resultsmix-resultsrev)/2;
      
      anglerev=flipud(anglerev);
      anglemix=angle;
      anglemix=(anglemix-anglerev)/2;
      
      patternrev=flipud(patternrev);
      patternmix=pattern;
      patternmix=(patternmix-patternrev)/2;
            resultadosmixC{xc}=resultsmix;
      resultadosreverseC{xc}=resultsrev;
      
      anglemixC{xc}=anglemix;
      anglemixreverseC{xc}=anglerev;
      
      patternmixC{1,xc}=patternmix;
      
      
  resultsmix=[];
  anglemix=[];
  patternmix=[];
      
  end
  
  resultadosC{xc}=results;
  anguloC{xc}=angle;
  patterC{xc}=pattern;
  results=[];
  angle=[];
  pattern=[];
%   destino15=strcat(pathnamecat{seedlings},'pattern.csv');
%   csvwrite(destino15,pattern);
%   if reverse==1
%       resultadosmix{xc}=resultsmix;
%       resultadosreverse{xc}=resultsrev;
%       
%       anglemix{xc}=anglemix;
%       anglemixreverse{xc}=anglerev;
%       
%       patternmixC{1,xc}=patternmix;
%       
%       
%   resultsmix=[];
%   anglemix=[];
%   patternmix=[];
%   end
    end
end

if (app.cicloscathy(seedlings)==2)
    for xc=1:app.setshy
  [results21,angle21,pattern21]=app.growthcalculatorhy(puntosxcat21,puntosycat21,app.objectPointscathy{seedlings,1,xc},timefilename,start1,end1,app.visualhy);  
  [results22,angle22,pattern22]=app.growthcalculatorhy(puntosxcat22,puntosycat22,app.objectPointscathy{seedlings,2,xc},timefilename,end1,end2,app.visualhy); 
  results=cat(1,results21,results22);
  patternC{1,app.setshy}=pattern21;
  patternC{2,app.setshy}=pattern22;
  
  if app.reversehy==1
      [resultsrev21,anglerev21,patternrev21]=app.growthcalculatorhy(puntosxcatrev21,puntosycatrev21,app.objectPointscathy{seedlings,3,xc},timefilenamerev,start1,end1,app.visualhy);  
      [resultsrev22,anglerev22,patternrev22]=app.growthcalculatorhy(puntosxcatrev22,puntosycatrev22,app.objectPointscathy{seedlings,2,xc},timefilenamerev,end1,end2,app.visualhy); 
      resultsrev=cat(1,resultsrev21,resultsrev22);
      resultsrev=flipud(resultsrev);
      patternrev21=flipud(patternrev21);
      patternrev22=flipud(patternrev22);
      resultsmix=results;
      
%       if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix-resultsrev)/2;
      patternmix21=(pattern21-patternrev21)/2;
      patternmix22=(pattern22-patternrev22)/2;
%       test=1;
%       end
      resultadosmixC{xc}=resultsmix;
      resultadosreverseC{xc}=resultsrev;

      patternmixC{1,app.setshy}=patternmix21;
      patternmixC{2,app.setshy}=patternmix22;
      resultsmix=[]
  end
    resultadosC{xc}=results;
  results=[];
%   if reverse==1
%       resultadosmix{xc}=resultsmix;
%       resultadosreverse{xc}=resultsrev;
%   resultsmix=[];
%   end
    
    end


end

if (app.cicloscathy(seedlings)==3)
    for xc=1:app.setshy
  [results31,angle31,pattern31]=app.growthcalculatorhy(puntosxcat31,puntosycat31,app.objectPointscathy{seedlings,1,xc},timefilename,start1,end1,app.visualhy);  
  [results32,angle32,pattern32]=app.growthcalculatorhy(puntosxcat32,puntosycat32,app.objectPointscathy{seedlings,2,xc},timefilename,end1,end2,app.visualhy); 
  [results33,angle33,pattern33]=app.growthcalculatorhy(puntosxcat33,puntosycat33,app.objectPointscathy{seedlings,3,xc},timefilename,end2,end3,app.visualhy); 
  results=cat(1,results31,results32,results33);
  patternC{1,app.setshy}=pattern31;
  patternC{2,app.setshy}=pattern32;
  patternC{3,app.setshy}=pattern33;
  
  if app.reversehy==1
      [resultsrev31,anglerev31,patternrev31]=app.growthcalculatorhy(puntosxcatrev31,puntosycatrev31,app.objectPointscathy{seedlings,4,xc},timefilenamerev,start1,end1,app.visualhy);  
      [resultsrev32,anglerev32,patternrev32]=app.growthcalculatorhy(puntosxcatrev32,puntosycatrev32,app.objectPointscathy{seedlings,3,xc},timefilenamerev,end1,end2,app.visualhy); 
      [resultsrev33,anglerev33,patternrev33]=app.growthcalculatorhy(puntosxcatrev33,puntosycatrev33,app.objectPointscathy{seedlings,2,xc},timefilenamerev,end2,end3,app.visualhy); 
      resultsrev=cat(1,resultsrev31,resultsrev32,resultsrev33);
      resultsrev=flipud(resultsrev);
      patternrev31=flipud(patternrev31);
      patternrev32=flipud(patternrev32);
      patternrev33=flipud(patternrev33);
      resultsmix=results;
      resultadosmixC{xc}=resultsmix;
      resultadosreverseC{xc}=resultsrev;


%       if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix-resultsrev)/2;
      patternmix31=(pattern31-patternrev31)/2;
      patternmix32=(pattern32-patternrev32)/2;
      patternmix33=(pattern33-patternrev33)/2;
      test=1;
      patternmixC{1,app.setshy}=patternmix31;
      patternmixC{2,app.setshy}=patternmix32;
      patternmixC{3,app.setshy}=patternmix33;
      resultsmix=[];
%       end
  end
  
  resultadosC{xc}=results;
  results=[];
%   if reverse==1
%       resultadosmix{xc}=resultsmix;
%       resultadosreverse{xc}=resultsrev;
%   resultsmix=[];
%   end

    end
    
    
end


if (app.cicloscathy(seedlings)==4)
    for xc=1:app.setshy
  [results41,angle41,pattern41]=app.growthcalculatorhy(puntosxcat41,puntosycat41,app.objectPointscathy{seedlings,1,xc},timefilename,start1,end1,app.visualhy);  
  [results42,angle42,pattern42]=app.growthcalculatorhy(puntosxcat42,puntosycat42,app.objectPointscathy{seedlings,2,xc},timefilename,end1,end2,app.visualhy); 
  [results43,angle43,pattern43]=app.growthcalculatorhy(puntosxcat43,puntosycat43,app.objectPointscathy{seedlings,3,xc},timefilename,end2,end3,app.visualhy); 
  [results44,angle44,pattern44]=app.growthcalculatorhy(puntosxcat44,puntosycat44,app.objectPointscathy{seedlings,4,xc},timefilename,end3,end4,app.visualhy); 
  results=cat(1,results41,results42,results43,results44);
  patternC{1,app.setshy}=pattern41;
  patternC{2,app.setshy}=pattern42;
  patternC{3,app.setshy}=pattern43;
  patternC{4,app.setshy}=pattern44;
  
  if app.reversehy==1
      [resultsrev41,anglerev41,patternrev41]=app.growthcalculatorhy(puntosxcatrev41,puntosycatrev41,app.objectPointscathy{seedlings,5,xc},timefilenamerev,start1,end1,app.visualhy);  
      [resultsrev42,anglerev42,patternrev42]=app.growthcalculatorhy(puntosxcatrev42,puntosycatrev42,app.objectPointscathy{seedlings,4,xc},timefilenamerev,end1,end2,app.visualhy); 
      [resultsrev43,anglerev43,patternrev43]=app.growthcalculatorhy(puntosxcatrev43,puntosycatrev43,app.objectPointscathy{seedlings,3,xc},timefilenamerev,end2,end3,app.visualhy); 
      [resultsrev44,anglerev44,patternrev44]=app.growthcalculatorhy(puntosxcatrev44,puntosycatrev44,app.objectPointscathy{seedlings,2,xc},timefilenamerev,end3,end4,app.visualhy); 
      resultsrev=cat(1,resultsrev41,resultsrev42,resultsrev43,resultsrev44);
      resultsrev=flipud(resultsrev);
      patternrev41=flipud(patternrev41);
      patternrev42=flipud(patternrev42);
      patternrev43=flipud(patternrev43);
      patternrev44=flipud(patternrev44);
      resultsmix=results;
      resultadosmixC{xc}=resultsmix;
      resultadosreverseC{xc}=resultsrev;


%       if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix-resultsrev)/2;
      patternmix41=(pattern41-patternrev41)/2;
      patternmix42=(pattern42-patternrev42)/2;
      patternmix43=(pattern43-patternrev43)/2;
      patternmix44=(pattern44-patternrev44)/2;
      
      patternmixC{1,app.setshy}=patternmix41;
      patternmixC{2,app.setshy}=patternmix42;
      patternmixC{3,app.setshy}=patternmix43;
      patternmixC{4,app.setshy}=patternmix44;
      resultsmix=[];
%       test=1;
%       end
  end
 resultadosC{xc}=results;
  results=[];
%   if reverse==1
%       resultadosmix{xc}=resultsmix;
%       resultadosreverse{xc}=resultsrev;
%   resultsmix=[];
%   end

  
    end

    
end

if app.angulacionhy==1
destino14=strcat(app.pathnamecathy{seedlings},'Angle.csv');
if angulo{1}<=angulo{2}
    angulofinal=((angulo{2}-angulo{1}));
else
    angulofinal=((angulo{1}-angulo{2}));
end
csvwrite(destino14,angulofinal);
else


destino13=strcat(app.pathnamecathy{seedlings},'CSV.csv');
for xd=1:app.setshy
    numeroa=num2str(xd);
destino1=strcat(app.pathnamecathy{seedlings},app.listofgenotypeshy{seedlings},numeroa,'F.csv');
destino12=strcat(app.pathnamecathy{seedlings},app.listofgenotypeshy{seedlings},numeroa,'R.csv');
destino11=strcat(app.pathnamecathy{seedlings},numeroa,'CSV.csv');
for xp=1:app.cicloscathy(seedlings)
    numerob=num2str(xp);
destinoP=strcat(app.pathnamecathy{seedlings},app.listofgenotypeshy{seedlings},numeroa,numerob,'pattern.csv');
csvwrite(destinoP,patternC{xp,xd});
if app.reversehy==1
csvwrite(destinoP,patternmixC{xp,xd});       
end

end
if app.reversehy==1
resultadosfinal=cat(2,resultadosfinal,resultadosmixC{xd});
csvwrite(destino11,resultadosC{xd});

csvwrite(destino11,resultadosmixC{xd});

csvwrite(destino12,resultadosreverseC{xd});

csvwrite(destino1,resultadosC{xd});

% csvwrite(destinoP,patternmixC{xd});
else
    resultadosfinal=cat(2,resultadosfinal,resultadosC{xd});
    csvwrite(destino11,resultadosC{xd});


end
end
resultadosfinal=mean(resultadosfinal,2);
csvwrite(destino13,resultadosfinal);
end


app.EditFieldMessage.Value='ALL DONE';


end
        end

        % Button pushed function: LoadfirstpicoftheexperimentButton
        function LoadfirstpicoftheexperimentButtonPushed(app, event)

if app.countersm==0
[filename, pathname] = uigetfile({'*.*'},'File Selector');
timefilename=strcat(pathname,filename);
 fileinfo=dir(timefilename);
%d = System.IO.File.GetCreationTime(timefilename);
% [Y,M,D,H,MN,S] = datevec(fileinfo.date);
% Y=d.Year; M=d.Month; D=d.Day; H=d.Hour; MN=d.Minute; S=d.Second;

app.time0sm=double(etime(datevec(fileinfo.datenum),datevec('2019/9/30 00:00:00')));
app.countersm=1;
end
        end

        % Button pushed function: LoadpicwhentreatmentstartedButton
        function LoadpicwhentreatmentstartedButtonPushed(app, event)

if app.countersm==1
[filename, pathname] = uigetfile({'*.*'},'File Selector');
timefilename=strcat(pathname,filename);
 fileinfo=dir(timefilename);
% [Y,M,D,H,MN,S] = datevec(fileinfo.date);
%d = System.IO.File.GetCreationTime(timefilename);
% Y=d.Year; M=d.Month; D=d.Day; H=d.Hour; MN=d.Minute; S=d.Second;
app.timeETONsm=double(etime(datevec(fileinfo.datenum),datevec('2019/9/30 00:00:00')));
app.countersm=2;
end          
        end

        % Button pushed function: LoadpicwhentreatmentendedButton
        function LoadpicwhentreatmentendedButtonPushed(app, event)

if app.countersm==2

[filename, pathname] = uigetfile({'*.*'},'File Selector');
timefilename=strcat(pathname,filename);
 fileinfo=dir(timefilename);
% [Y,M,D,H,MN,S] = datevec(fileinfo.date);
%d = System.IO.File.GetCreationTime(timefilename);
% Y=d.Year; M=d.Month; D=d.Day; H=d.Hour; MN=d.Minute; S=d.Second;
app.timeETOFFsm=double(etime(datevec(fileinfo.datenum),datevec('2019/9/30 00:00:00')));
app.startsm=round((app.timeETONsm-app.time0sm)/60);
app.finsm=round((app.timeETOFFsm-app.time0sm)/60);

 text=sprintf('Treatment started at time: %d and ended at time: %d.',app.startsm,app.finsm);
app.EditField_2.Value=text;  
app.countersm=3;
end

        end

        % Button pushed function: LoadfirstpicforthisplantButton
        function LoadfirstpicforthisplantButtonPushed(app, event)
app.timefsm=[];
[app.filenamesm, app.pathnamesm] = uigetfile({'*.*'},'File Selector');
timefilename=strcat(app.pathnamesm,app.filenamesm);
 fileinfo=dir(timefilename);
% [Y,M,D,H,MN,S] = datevec(fileinfo.date);
%d = System.IO.File.GetCreationTime(timefilename);
% Y=d.Year; M=d.Month; D=d.Day; H=d.Hour; MN=d.Minute; S=d.Second;
app.timeFirstsm=double(etime(datevec(fileinfo.datenum),datevec('2019/9/30 00:00:00')));

app.originalsplitsm=strsplit(app.filenamesm,'.');
timecounterstr=app.originalsplitsm{end-1};
timecounter=str2num(timecounterstr);
app.totalNimagessm=0;

Files=dir(app.pathnamesm);
N=app.natsortfiles({Files.name});
for i=1:length(Files)
FileNames=N(i);
     W = ~isempty(cell2mat(strfind(FileNames,'.tif')));
     
     if contains(FileNames,'jpeg')
         W = ~isempty(strfind(FileNames,'jpeg'));
     end

      if W
          app.totalNimagessm=app.totalNimagessm+1;
          FileNames=cell2mat(FileNames);
        originalsplit1=strsplit(FileNames,'.');
        timecounterstr1=originalsplit1{end-1};
        timecounter1=str2num(timecounterstr1);
        if timecounter1>=timecounter
            timefilename=strcat(app.pathnamesm,FileNames);
            fileinfo=dir(timefilename);
%             [Y,M,D,H,MN,S] = datevec(fileinfo.date);
%            d = System.IO.File.GetCreationTime(timefilename);
%             Y=d.Year; M=d.Month; D=d.Day; H=d.Hour; MN=d.Minute; S=d.Second;
            time=double(etime(datevec(fileinfo.datenum),datevec('2019/9/30 00:00:00')));
            app.timefsm=cat(1,app.timefsm,time);
          
        end
            
      end
end           
        end

        % Button pushed function: LoadgrowthfilecsvButton
        function LoadgrowthfilecsvButtonPushed(app, event)
app.originalsplitsm=strsplit(app.pathnamesm,filesep);
app.genotypesm=app.originalsplitsm(1,end-2);
app.genotypesm=char(app.genotypesm);

if app.countersm == 4
    delete(app.h1sm);
    delete(app.h2sm);
    delete(app.h3sm);
    %delete(h4);
end


if app.countersm==4 || app.countersm == 3 || app.countersm==5
[app.filenamesm, app.pathnamesm] = uigetfile({'*.csv'},'File Selector'); %you can add
%this back if you want to manually select a file that is not called CSV.csv
%in the Raw directory

%filename=char('CSV.csv');

app.countersm=4;
app.EditField_3.Value=app.genotypesm;

name=strcat(app.pathnamesm,app.filenamesm);
values=csvread(name);
valuessize=size(values);
 %this gets the times
 app.timefsm=app.timefsm(1:valuessize(1)+1);
 times=(app.timefsm-app.time0sm)/60;
 timesA=times(1:end-1,:);
 timesB=times(2:end,:);
 newtimes=timesB-timesA;
 values=values./newtimes;
 
app.trsm=round((app.timefsm-app.time0sm)/60);
app.tsm=((app.timefsm-app.time0sm)/60);


app.tsm=app.tsm(1:valuessize(1,1),:);
clear('app.timefsm');
duration=app.tsm(end,1)-app.tsm(1,1);


%x=(1:numberofframes).';
app.cyclesm=app.tsm(2,1)-app.tsm(1,1);
%intervalo5=floor(start+360/5);%change this if you change time

%intervalo=5;%this is the time between pictures or the theoretical time between pictures we want to use in the analysis


%inicio=app.startsm-120;%2 hours before ethylene starts

%intervalos5=floor((duration-inicio)/intervalo);%change this if you change time
if app.entro==0
intervalos5=ceil(duration/app.intervalo);
%app.xscalesm=linspace(inicio,inicio+(intervalos5)*intervalo,intervalos5+1);%240 refers to 4 hours after ethylene starts 73 is 120+240/5 +1
app.xscalesm=linspace(0,intervalos5*app.intervalo,intervalos5+1);
app.entro=1;
end
app.ysm=values(:,1);
app.ysm=app.ysm(1:valuessize(1,1),:);
app.ysm=app.ysm/app.cyclesm;
app.fsm=fit(app.tsm,app.ysm,'smoothingspline','SmoothingParam',0.001);
app.fprimesm=fit(app.tsm,app.ysm,'smoothingspline','SmoothingParam',1);
avgng=0;
avgngprime=0;

contadordeintervalos=0;

if app.autonorm==1
for ng=app.startsm-120:app.intervalo:app.startsm %calculate normalized grwoth 2 hoursbefore the ethylene starts
    normalizedgrowth=app.fsm(ng);
    avgng=avgng+normalizedgrowth;
    contadordeintervalos=contadordeintervalos+1;
end
for ng=app.startsm-120:app.intervalo:app.startsm %calculate normalized grwoth 2 hoursbefore the ethylene starts
    normalizedgrowth=app.fprimesm(ng);
    avgngprime=avgngprime+normalizedgrowth;
end
else
for ng=app.inicostart:app.intervalo:app.inicioend %calculate normalized grwoth 2 hoursbefore the ethylene starts
    normalizedgrowth=app.fsm(ng);
    avgng=avgng+normalizedgrowth;
    contadordeintervalos=contadordeintervalos+1;
end
for ng=app.inicostart:app.intervalo:app.inicioend %calculate normalized grwoth 2 hoursbefore the ethylene starts
    normalizedgrowth=app.fprimesm(ng);
    avgngprime=avgngprime+normalizedgrowth;
end    


end

avgng=avgng/contadordeintervalos;
avgngprime=avgngprime/contadordeintervalos;
yprime=app.ysm*100/avgngprime;
app.ysm=app.ysm*100/avgng;

app.fsm=fit(app.tsm,app.ysm,'smoothingspline','SmoothingParam',0.001);
app.fprimesm=fit(app.tsm,yprime,'smoothingspline','SmoothingParam',1);

app.yysm=app.fsm(app.xscalesm);
app.yyprimesm=app.fprimesm(app.xscalesm);
hold(app.UIAxes2,'on');
app.h1sm=plot(app.xscalesm,app.yysm,'linewidth',3,'Parent',app.UIAxes2);
app.h2sm=plot(app.startsm-2*app.cyclesm,app.fsm(app.startsm-2*app.cyclesm),'r*','MarkerSize',15,'Parent',app.UIAxes2);
app.h3sm=plot(app.finsm-2*app.cyclesm,app.fsm(app.finsm-2*app.cyclesm),'g*','MarkerSize',15,'Parent',app.UIAxes2);

end
           
        end

        % Button pushed function: AddlastroottoAllRootsButton
        function AddlastroottoAllRootsButtonPushed(app, event)

if app.countersm==4
   app.countersm=5;
framesC=size(app.concatsm);
framesY=size(app.yysm);
if framesC(1,1)>0 && framesY(1,1)<framesC(1,1)
    app.ysm(framesC(1,1)+1:end,:)=[];
    app.yysm(framesC(1,1)+1:end,:)=[];
    app.tsm(framesC(1,1)+1:end,:)=[];
    app.yyprimesm(framesC(1,1)+1:end,:)=[];
    app.xscalesm(:,framesC(1,1)+1:end)=[];
end

if framesC(1,1)>0 && framesY(1,1)>framesC(1,1)
    app.concatsm(framesY(1,1)+1:end,:,:)=[];
    app.concatprimesm(framesY(1,1)+1:end,:,:)=[];
    app.concattsm(framesY(1,1)+1:end,:)=[];
    
    app.ysm(framesY(1,1)+1:end,:,:)=[];
    app.smyy(framesY(1,1)+1:end,:,:)=[];
    app.yyprimesm(framesY(1,1)+1:end,:,:)=[];
    app.tsm(framesY(1,1)+1:end,:,:)=[];  
end

app.listsm=cat(2,app.listsm,app.genotypesm);
app.concatsm=cat(3,app.concatsm,app.yysm);
app.concatprimesm=cat(3,app.concatprimesm,app.yyprimesm);
  
app.listofgenotypessm{end+1}=app.genotypesm;
app.listofnamessm{end+1}=app.filenamesm;
app.ListoffilesTextArea_2.Value=app.listofgenotypessm;
app.plantassm=app.plantassm+1;
end            
        end

        % Button pushed function: SmoothingSplineofAllRootsButton
        function SmoothingSplineofAllRootsButtonPushed(app, event)

if app.countersm == 5 || app.countersm == 4
hold(app.UIAxes2,'off');

app.mediassm=mean(app.concatsm,3);
app.stdvsm=std(app.concatsm,[],3);
app.SEMsm=app.stdvsm/sqrt(app.plantassm);

app.mediasprimesm=mean(app.concatprimesm,3);
app.stdvprimesm=std(app.concatprimesm,[],3);
app.SEMprimesm=std(app.concatprimesm,[],3)/sqrt(app.plantassm);
errorbar(app.xscalesm,app.mediasprimesm,app.SEMprimesm,'linewidth',3,'Parent',app.UIAxes2);
% hold(app.UIAxes2,'on');
% sp1=get(gca,'ylim');
% delete(gca);
% plot([app.startsm-2*app.cyclesm app.startsm-2*app.cyclesm],sp1,'Color',[1 0 0],'Parent',app.UIAxes2);
% plot([app.finsm-2*app.cyclesm app.finsm-2*app.cyclesm],sp1,'Color',[1 0 0],'Parent',app.UIAxes2);

app.countersm=6;
end            
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)

if app.countersm==6
[FileName,PathName] = uiputfile;
tabla=strcat(PathName,FileName,'fits','.csv');
tablafitSEM=strcat(PathName,FileName,'fitsSEM','.csv');
lista=strcat(PathName,FileName,'names','.txt');
%AllOuts=strcat(PathName,FileName,'OUTS','.csv');
tablaprime=strcat(PathName,FileName,'fitsnosmoothing','.csv');
tablaprimeraw=strcat(PathName,FileName,'fitsnosmoothingRAW','.csv');
tablaSEM=strcat(PathName,FileName,'SEM','.csv');
numberofseedlings=size(app.concatprimesm);
numberofseedlings=numberofseedlings(1,3);

primeraw=[];
primeraw(:,1)=app.xscalesm.';
for x=1:numberofseedlings
    primeraw(:,x+1)=app.concatprimesm(:,1,x);
end

table=cat(2,app.xscalesm.',app.mediassm,app.stdvsm);
table=array2table(table);
writetable(table,tabla);

tableprime=cat(2,app.xscalesm.',app.mediasprimesm,app.stdvprimesm);
tableprime=array2table(tableprime);
writetable(tableprime,tablaprime);

tablaprimeSEM=cat(2,app.xscalesm.',app.mediasprimesm,app.SEMprimesm);
tablaprimeSEM=array2table(tablaprimeSEM);
writetable(tablaprimeSEM,tablaSEM);

tablefitSEM=cat(2,app.xscalesm.',app.mediassm,app.SEMsm);
tablefitSEM=array2table(tablefitSEM);
writetable(tablefitSEM,tablafitSEM);

tableprimeraw=array2table(primeraw);
writetable(tableprimeraw,tablaprimeraw);

%writetable(OUTS,AllOuts);
app.listofnamessm=cell2table(app.listofnamessm);
app.listofgenotypessm=cell2table(app.listofgenotypessm);
%writetable(listofnames,);
writetable(app.listofgenotypessm,lista);
% save('medias','mediag');
% save('stdv','stdvg');
% save('start','startg');
% save('fin','fing');
% save('cycle','cycleg');
% save('xscale','xscaleg');
% save('f','fg');
% save('SEM','SEMg');
end            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
% Set current data to the selected data set.
switch value
case '1' % User selects peaks.
   app.segmentsRT = 1;
case '2' % User selects membrane.
   app.segmentsRT = 2;
case '3' % User selects sinc.
   app.segmentsRT = 3;
case '4' % User selects sinc.
   app.segmentsRT = 4;  
end

        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if value == 0
            app.reverseRT=0;
            else
            app.reverseRT=1;
            end          
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
if value == "Yes"
    app.visualRT=1;
else
    app.visualRT=0;
end              
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
                if value == 0
                    app.saveRT=1;
                else
                    app.saveRT=0;
                end
        end

        % Button pushed function: SelectlastframetoanalyzeButton
        function SelectlastframetoanalyzeButtonPushed(app, event)
prompt = {'Enter last image to analyze: '};
title = 'Input';
dims = [1 35];
x1 = inputdlg(prompt,title,dims);
app.xRT=str2num(x1{1});           
        end

        % Button pushed function: SelectfirstimageButton
        function SelectfirstimageButtonPushed(app, event)

cy=1;


app.contadorRT=app.contadorRT+1;
if app.segmentsRT == 1 
    ciclos=1;
end

if app.segmentsRT == 2
    ciclos=2;
end

if app.segmentsRT == 3
    ciclos=3;
end

if app.segmentsRT == 4
    ciclos=4;
end

if app.reverseRT == 1
    ciclos=ciclos+1;
end

while cy<=ciclos
text=sprintf('Selected segment #: %d out of: %d.',cy,ciclos);
app.EditField.Value=text;
[filename pathname] = uigetfile({'*.tif';'*.jpg';'*.jpeg'},'File Selector');
app.pathnamecatRT{app.contadorRT}=pathname;
names=strcat(pathname,'*','.tif');
if contains(filename,'jpeg')
    names=strcat(pathname,'*','.jpeg');
end

app.namescatRT{app.contadorRT}=names;
name = strcat(pathname, filename);
app.namecatRT{app.contadorRT}=name;
I=imread(name);

imshow(I,'Parent',app.UIAxes);%display image

prompt = {'Do you want to select this image?: '};
title = 'Input';
dims = [1 35];
definput = {'Y'};
x = inputdlg(prompt,title,dims,definput);

if strcmp(x,'n') || (strcmp(x,'N'))
    
    continue;
end

originalsplit=strsplit(pathname,filesep);
genotype=originalsplit(1,end-2);
%genotyperoot=originalsplit(1,end-3);
%genotyperoot=char(genotyperoot);
genotype=char(genotype);
app.listofgenotypesRT{end+1}=genotype;
cytxt=num2str(cy);
app.listofgenotypesrootRT{end+1}=strcat(genotype,'-',cytxt);
splitfilename=strsplit(filename,'.');
filenumber=splitfilename{end-1};
if numel(filenumber)>4
    filenumber=filenumber(end-3:end);
end

    
app.filenumbercatRT{app.contadorRT,cy}=filenumber;

%listofgenotypesroot{end+1}=genotyperoot;

app.ListoffilesTextArea.Value=app.listofgenotypesrootRT;

%% select area to use as control
app.EditField.Value='Select area you want to use as control';
figure;
imshow(I);
objectRegion=round(getPosition(imrect));
app.objectRegioncatRT{app.contadorRT,cy}=objectRegion;
app.EditField.Value='Press any key when ready';
pause;

%% select area you want to track
app.EditField.Value='Select area you want to track';
objectRegion1=round(getPosition(imrect));
app.objectRegioncat1RT{app.contadorRT,cy}=objectRegion1;
app.EditField.Value='Press any key when ready';
pause;
cy=cy+1;
close(gcf);
end
%%send handles
if app.reverseRT==1
    ciclos=ciclos-1;
end

app.cicloscatRT(1,app.contadorRT)=ciclos;


app.EditField.Value='If you want to add another image click "Select First Image". If done, click "Run"';
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
app.EditField.Value='Creating movie .avi files. This can take a while';

numberofframes={};

%% loop for each seedling creating a movie AVI

for seedlings=1:app.contadorRT
Files=dir(app.namescatRT{seedlings}); %all files in a directory
N=app.natsortfiles({Files.name});
destino=strcat(app.pathnamecatRT{seedlings},'Video.avi');%destination for the Video file
%numberofframes{seedlings}=length(Files);
numberofframes{seedlings}=0;
writerObj = VideoWriter(destino);% Create a video writer objec
%writerObj.FrameRate = 10;% Set frame rate
open(writerObj) % Open video writer object and write frames sequentially
if app.xRT==0
for i = 1:length(Files) %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescatRT{seedlings},'jpeg')
         W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecatRT{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
     
     writeVideo(writerObj, input); % Write frame now 
     
     numberofframes{seedlings}=numberofframes{seedlings}+1;
      
 %     if i==length(Files)
 %       writeVideo(writerObj, input); % Write frame now  
  %    end
     end
end
else
    for i = 1:app.xRT %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescatRT{seedlings},'jpeg')
         W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecatRT{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     writeVideo(writerObj, input); % Write frame now 
     
     
      numberofframes{seedlings}=numberofframes{seedlings}+1;
      
 %     if i==length(Files)
 %       writeVideo(writerObj, input); % Write frame now  
  %    end
     end
    end
    

end

% Close the video writer object
close(writerObj);
end


%%loop to create a reverse video is this option is selected
if app.reverseRT==1
for seedlings=1:app.contadorRT
Files=dir(app.namescatRT{seedlings}); %all files in a directory
N=natsortfiles({Files.name});
destino=strcat(app.pathnamecatRT{seedlings},'Videorev.avi');%destination for the Video file
%numberofframes{seedlings}=length(Files);


writerObj = VideoWriter(destino);% Create a video writer objec
%writerObj.FrameRate = 10;% Set frame rate
open(writerObj) % Open video writer object and write frames sequentially
if app.xRT==0
for i = length(Files):-1:1 %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescatRT{seedlings},'jpeg')
         W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecatRT{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
     
     writeVideo(writerObj, input); % Write frame now 


     end
end

else
    for i = app.xRT:-1:1 %go through all the frames in the folder
     FileNames=N(i);
     %if contains(FileNames,'tif')%select only files with tif extension
     W = ~isempty(strfind(FileNames,'tif'));
     if contains(app.namescatRT{seedlings},'jpeg')
         W = ~isempty(strfind(FileNames,'jpeg'));
     end
     if W
     nombre=strcat(app.pathnamecatRT{seedlings},FileNames);
     nombre=char(nombre);
     input = imread(nombre);
     input = im2uint8(input);
     if i==1 && app.FRAMEcorrection==1
         ref=input;
     end
%      if i>app.FRAMEcorrection && i <=app.FRAMEcorrection+3
%      input=imhistmatch(input,ref);
%      end
     if app.FRAMEcorrection==1
         input=imhistmatch(input,ref,256);
     end
     text=num2str(i);
     position =  [10,10];
     input=insertText(input,position,text,'FontSize',50);
     
     writeVideo(writerObj, input); % Write frame now
     end
     end
end

% Close the video writer object
close(writerObj);
end 
    
end


%% Track a Face in Scene

%%start loop for each seedling
for seedlings=1:app.contadorRT

text=sprintf('Analysing image: %s',app.listofgenotypesRT{seedlings});
app.EditField.Value=text;  
     drawnow;
%% 
% Create System objects for reading and displaying video and for drawing a bounding box of the object. 

timefilename=strcat(app.pathnamecatRT{seedlings},'Video.avi');
videoFileReader = vision.VideoFileReader(timefilename);
videoPlayer = vision.VideoPlayer('Position',[100,100,680,520]); %
videoPlayer1 = vision.VideoPlayer('Position',[1000,1000,680,520]); %
%% 
% Read the first video frame, which contains the object, define the region.
%%%%%%%%%objectFrame = step(videoFileReader);

if app.reverseRT==1
    % Create System objects for reading and displaying video and for drawing a bounding box of the object. 

timefilenamerev=strcat(app.pathnamecatRT{seedlings},'Videorev.avi');
videoFileReaderrev = vision.VideoFileReader(timefilenamerev);
videoPlayerrev = vision.VideoPlayer('Position',[100,100,680,520]); %
videoPlayer1rev = vision.VideoPlayer('Position',[1000,1000,680,520]); %
%% 
% Read the first video frame, which contains the object, define the region.
%%%%%%%%%%%%%objectFramerev = step(videoFileReaderrev);
end

% Show initial frame with a red bounding box.
%objectImage = insertShape(objectFrame,'Rectangle',objectRegioncat{seedlings,cy},'Color','red'); 
%objectImage1 = insertShape(objectFrame,'Rectangle',objectRegioncat1{seedlingscy},'Color','red'); 

%figure;
%subplot(1,2,1);
%imshow(objectImage);
%subplot(1,2,2);
%imshow(objectImage1);
%title('Red box shows object region');
%% 
% Detect interest points in the object region.
%points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat{seedlings});
%points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',objectRegioncat1{seedlings});
%% 
% Display the detected points.
%pointImage = insertMarker(objectFrame,points.Location,'+','Color','white');
%pointImage1 = insertMarker(objectFrame,points1.Location,'+','Color','white');
%figure;
%subplot(1,2,1);
%imshow(pointImage);
%subplot(1,2,2);
%imshow(pointImage1);
%title('Detected interest points');
%% 
% Create a tracker object.
%tracker = vision.PointTracker('MaxBidirectionalError',1);
%tracker1 = vision.PointTracker('MaxBidirectionalError',1);
%% 
% Initialize the tracker.
%initialize(tracker,points.Location,objectFrame);
%initialize(tracker1,points1.Location,objectFrame);
%% 
% Read, track, display points, and results in each video frame.
puntosx=[];
puntosy=[];
puntosxrev=[];
puntosyrev=[];


puntosx1=[];
puntosy1=[];
puntosx1rev=[];
puntosy1rev=[];

%growth1=[];
%growthT1=[];
contador10=1;

destino2=strcat(app.pathnamecatRT{seedlings},'VideoTracker.avi');%destination for the Video file


writerObj1 = VideoWriter(destino2);% Create a video writer objec
writerObj1.FrameRate = 10;% Set frame rate
open(writerObj1) % Open video writer object and write frames sequentially
if app.cicloscatRT(seedlings)==1
    start1=str2num(app.filenumbercatRT{seedlings,1})+1;
    start2=start1;
    start3=start1;
    start4=start1;
    end1=numberofframes{seedlings}+1;
    end2=end1;
    end3=end1;
    end4=end1;
    puntosxcat11=[];puntosycat11=[];puntosx1cat11=[];puntosy1cat11=[];
    if app.reverseRT==1
        end1=(str2num(app.filenumbercatRT{seedlings,2})+2); 
        end2=end1;
        end3=end1;
        end4=end1;
    end
    
end

if app.cicloscatRT(seedlings)==2
    start1=str2num(app.filenumbercatRT{seedlings,1})+1;
    start2=str2num(app.filenumbercatRT{seedlings,2})+1;
    start3=start2;
    start4=start2;
    end1=str2num(app.filenumbercatRT{seedlings,2})+1;
    end2=numberofframes{seedlings}+1;
    end3=end2;
    end4=end2;
    puntosxcat21=[];puntosycat21=[];puntosx1cat21=[];puntosy1cat21=[];
    puntosxcat22=[];puntosycat22=[];puntosx1cat22=[];puntosy1cat22=[];
    if app.reverseRT==1
    end2=(str2num(app.filenumbercatRT{seedlings,3})+2); 
    end3=end2;
    end4=end2;
    end
end

if app.cicloscatRT(seedlings)==3
    start1=str2num(app.filenumbercatRT{seedlings,1})+1;
    start2=str2num(app.filenumbercatRT{seedlings,2})+1;
    start3=str2num(app.filenumbercatRT{seedlings,3})+1;
    start4=start3;
    end1=str2num(app.filenumbercatRT{seedlings,2})+1;
    end2=str2num(app.filenumbercatRT{seedlings,3})+1;  
    end3=numberofframes{seedlings}+1;
    end4=end3;
    puntosxcat31=[];puntosycat31=[];puntosx1cat31=[];puntosy1cat31=[];
    puntosxcat32=[];puntosycat32=[];puntosx1cat32=[];puntosy1cat32=[];
    puntosxcat33=[];puntosycat33=[];puntosx1cat33=[];puntosy1cat33=[];
    if app.reverseRT==1
    end3=(str2num(app.filenumbercatRT{seedlings,4})+2);
    end4=end3;
    end
end


if app.cicloscatRT(seedlings)==4
    start1=str2num(app.filenumbercatRT{seedlings,1})+1;
    start2=str2num(app.filenumbercatRT{seedlings,2})+1;
    start3=str2num(app.filenumbercatRT{seedlings,3})+1;
    start4=str2num(app.filenumbercatRT{seedlings,4})+1;
    end1=str2num(app.filenumbercatRT{seedlings,2})+1;
    end2=str2num(app.filenumbercatRT{seedlings,3})+1;
    end3=str2num(app.filenumbercatRT{seedlings,4})+1;
    end4=numberofframes{seedlings}+1;
    puntosxcat41=[];puntosycat41=[];puntosx1cat41=[];puntosy1cat41=[];
    puntosxcat42=[];puntosycat42=[];puntosx1cat42=[];puntosy1cat42=[];
    puntosxcat43=[];puntosycat43=[];puntosx1cat43=[];puntosy1cat43=[];
    puntosxcat44=[];puntosycat44=[];puntosx1cat44=[];puntosy1cat44=[];
    if app.reverseRT==1
    end4=(str2num(app.filenumbercatRT{seedlings,5})+2);  
    end
end

while ~isDone(videoFileReader)
         
if (app.cicloscatRT(seedlings)==1) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = step(videoFileReader);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,1});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat11=cat(2,puntosxcat11,points(:,1));
      puntosycat11=cat(2,puntosycat11,points(:,2));   
        
      puntosx1cat11=cat(2,puntosx1cat11,points1(:,1));
      puntosy1cat11=cat(2,puntosy1cat11,points1(:,2));     
      
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end


       end

      if (app.cicloscatRT(seedlings)==1) & (contador10<=end1) & (contador10>start1)
        
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat11=cat(2,puntosxcat11,points(:,1));
      puntosycat11=cat(2,puntosycat11,points(:,2));   
        
      puntosx1cat11=cat(2,puntosx1cat11,points1(:,1));
      puntosy1cat11=cat(2,puntosy1cat11,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end


       if (app.cicloscatRT(seedlings)==2) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
      objectFrame = step(videoFileReader);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,1});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat21=cat(2,puntosxcat21,points(:,1));
      puntosycat21=cat(2,puntosycat21,points(:,2));   
        
      puntosx1cat21=cat(2,puntosx1cat21,points1(:,1));
      puntosy1cat21=cat(2,puntosy1cat21,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
       end
      
      if (app.cicloscatRT(seedlings)==2) & (contador10>start1) & (contador10<=end1)
 

      frame = step(videoFileReader);
      contador10=contador10+1;
      
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat21=cat(2,puntosxcat21,points(:,1));
      puntosycat21=cat(2,puntosycat21,points(:,2));   
        
      puntosx1cat21=cat(2,puntosx1cat21,points1(:,1));
      puntosy1cat21=cat(2,puntosy1cat21,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end


      end
      

       if (app.cicloscatRT(seedlings)==2) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);  
      
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat22=cat(2,puntosxcat22,points(:,1));
      puntosycat22=cat(2,puntosycat22,points(:,2));   
        
      puntosx1cat22=cat(2,puntosx1cat22,points1(:,1));
      puntosy1cat22=cat(2,puntosy1cat22,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end;


       end
       
       if app.cicloscatRT(seedlings)==2 & contador10>=end1 & contador10<=end2
      
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat22=cat(2,puntosxcat22,points(:,1));
      puntosycat22=cat(2,puntosycat22,points(:,2));   
        
      puntosx1cat22=cat(2,puntosx1cat22,points1(:,1));
      puntosy1cat22=cat(2,puntosy1cat22,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end


       if (app.cicloscatRT(seedlings)==3) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
         objectFrame = step(videoFileReader);
         contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,1});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
     
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat31=cat(2,puntosxcat31,points(:,1));
      puntosycat31=cat(2,puntosycat31,points(:,2));   
        
      puntosx1cat31=cat(2,puntosx1cat31,points1(:,1));
      puntosy1cat31=cat(2,puntosy1cat31,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end


       end
      
      if app.cicloscatRT(seedlings)==3 & contador10>start1 & contador10<=end1
      
      frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
     
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat31=cat(2,puntosxcat31,points(:,1));
      puntosycat31=cat(2,puntosycat31,points(:,2));   
        
      puntosx1cat31=cat(2,puntosx1cat31,points1(:,1));
      puntosy1cat31=cat(2,puntosy1cat31,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end
      
      
      if (app.cicloscatRT(seedlings)==3) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
       [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat32=cat(2,puntosxcat32,points(:,1));
      puntosycat32=cat(2,puntosycat32,points(:,2));   
        
      puntosx1cat32=cat(2,puntosx1cat32,points1(:,1));
      puntosy1cat32=cat(2,puntosy1cat32,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
       end
      if app.cicloscatRT(seedlings)==3 & contador10>=end1 & contador10<=end2

       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat32=cat(2,puntosxcat32,points(:,1));
      puntosycat32=cat(2,puntosycat32,points(:,2));   
        
      puntosx1cat32=cat(2,puntosx1cat32,points1(:,1));
      puntosy1cat32=cat(2,puntosy1cat32,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end
      
      
      if (app.cicloscatRT(seedlings)==3) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
        
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,3});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);


      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat33=cat(2,puntosxcat33,points(:,1));
      puntosycat33=cat(2,puntosycat33,points(:,2));   
        
      puntosx1cat33=cat(2,puntosx1cat33,points1(:,1));
      puntosy1cat33=cat(2,puntosy1cat33,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      
       end
      if app.cicloscatRT(seedlings)==3 & contador10>=end2 & contador10<=end3
      
          frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);


      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat33=cat(2,puntosxcat33,points(:,1));
      puntosycat33=cat(2,puntosycat33,points(:,2));   
        
      puntosx1cat33=cat(2,puntosx1cat33,points1(:,1));
      puntosy1cat33=cat(2,puntosy1cat33,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end


      if (app.cicloscatRT(seedlings)==4) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = step(videoFileReader);
        contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,1});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,1});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat41=cat(2,puntosxcat41,points(:,1));
      puntosycat41=cat(2,puntosycat41,points(:,2));   
        
      puntosx1cat41=cat(2,puntosx1cat41,points1(:,1));
      puntosy1cat41=cat(2,puntosy1cat41,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end


       end
      if app.cicloscatRT(seedlings)==4 & contador10>start1 & contador10<=end1
       frame = step(videoFileReader);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat41=cat(2,puntosxcat41,points(:,1));
      puntosycat41=cat(2,puntosycat41,points(:,2));   
        
      puntosx1cat41=cat(2,puntosx1cat41,points1(:,1));
      puntosy1cat41=cat(2,puntosy1cat41,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      end


      if (app.cicloscatRT(seedlings)==4) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat42=cat(2,puntosxcat42,points(:,1));
      puntosycat42=cat(2,puntosycat42,points(:,2));   
        
      puntosx1cat42=cat(2,puntosx1cat42,points1(:,1));
      puntosy1cat42=cat(2,puntosy1cat42,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end
      if app.cicloscatRT(seedlings)==4 & contador10>=end1 & contador10<=end2
       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat42=cat(2,puntosxcat42,points(:,1));
      puntosycat42=cat(2,puntosycat42,points(:,2));   
        
      puntosx1cat42=cat(2,puntosx1cat42,points1(:,1));
      puntosy1cat42=cat(2,puntosy1cat42,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end
      
      if (app.cicloscatRT(seedlings)==4) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,3});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat43=cat(2,puntosxcat43,points(:,1));
      puntosycat43=cat(2,puntosycat43,points(:,2));   
        
      puntosx1cat43=cat(2,puntosx1cat43,points1(:,1));
      puntosy1cat43=cat(2,puntosy1cat43,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
       end
      
      if app.cicloscatRT(seedlings)==4 & contador10>=end2 & contador10<=end3
       frame = step(videoFileReader);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat43=cat(2,puntosxcat43,points(:,1));
      puntosycat43=cat(2,puntosycat43,points(:,2));   
        
      puntosx1cat43=cat(2,puntosx1cat43,points1(:,1));
      puntosy1cat43=cat(2,puntosy1cat43,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end
      
      
      if (app.cicloscatRT(seedlings)==4) & (contador10==end3)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,4});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcat44=cat(2,puntosxcat44,points(:,1));
      puntosycat44=cat(2,puntosycat44,points(:,2));   
        
      puntosx1cat44=cat(2,puntosx1cat44,points1(:,1));
      puntosy1cat44=cat(2,puntosy1cat44,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end


       end
      if app.cicloscatRT(seedlings)==4 & contador10>=end3 & contador10<=end4
       frame = step(videoFileReader);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcat44=cat(2,puntosxcat44,points(:,1));
      puntosycat44=cat(2,puntosycat44,points(:,2));   
        
      puntosx1cat44=cat(2,puntosx1cat44,points1(:,1));
      puntosy1cat44=cat(2,puntosy1cat44,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1, out);
      end
      
      end


%if contador10==numberofframes{seedlings}
   % break
%end


end
%% 
% Release the video reader and player.
release(videoPlayer);
release(videoPlayer1);
release(videoFileReader);
close(writerObj1);


%%for the reverse video
if app.reverseRT ==1


destino3=strcat(app.pathnamecatRT{seedlings},'VideoTrackerReverse.avi');%destination for the Video file
contador10=1;    
    
writerObj1rev = VideoWriter(destino3);% Create a video writer objec
writerObj1rev.FrameRate = 10;% Set frame rate
open(writerObj1rev) % Open video writer object and write frames sequentially

if app.cicloscatRT(seedlings)==1
    start1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,2});
    end1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,1})+1;
    end2=end1;
    end3=end1;
    end4=end1;
    puntosxcatrev11=[];puntosycatrev11=[];puntosx1catrev11=[];puntosy1catrev11=[];
end

if app.cicloscatRT(seedlings)==2
    start1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,3});
    end1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,2})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,1})+1;
    end3=end2;
    end4=end2;
    puntosxcatrev21=[];puntosycatrev21=[];puntosx1catrev21=[];puntosy1catrev21=[];
    puntosxcatrev22=[];puntosycatrev22=[];puntosx1catrev22=[];puntosy1catrev22=[];
end

if app.cicloscatRT(seedlings)==3
    start1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,4});
    end1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,3})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,2})+1;  
    end3=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,1})+1;
    end4=end3;
    puntosxcatrev31=[];puntosycatrev31=[];puntosx1catrev31=[];puntosy1catrev31=[];
    puntosxcatrev32=[];puntosycatrev32=[];puntosx1catrev32=[];puntosy1catrev32=[];
    puntosxcatrev33=[];puntosycatrev33=[];puntosx1catrev33=[];puntosy1catrev33=[];
end


if app.cicloscatRT(seedlings)==4
    start1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,5});
    
    end1=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,4})+1;
    end2=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,3})+1;
    end3=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,2})+1;
    end4=numberofframes{seedlings}-str2num(app.filenumbercatRT{seedlings,1})+1;
    puntosxcatrev41=[];puntosycatrev41=[];puntosx1catrev41=[];puntosy1catrev41=[];
    puntosxcatrev42=[];puntosycatrev42=[];puntosx1catrev42=[];puntosy1catrev42=[];
    puntosxcatrev43=[];puntosycatrev43=[];puntosx1catrev43=[];puntosy1catrev43=[];
    puntosxcatrev44=[];puntosycatrev44=[];puntosx1catrev44=[];puntosy1catrev44=[];
end


while ~isDone(videoFileReaderrev)


       if (app.cicloscatRT(seedlings)==1) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = step(videoFileReaderrev);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev11=cat(2,puntosxcatrev11,points(:,1));
      puntosycatrev11=cat(2,puntosycatrev11,points(:,2));   
        
      puntosx1catrev11=cat(2,puntosx1catrev11,points1(:,1));
      puntosy1catrev11=cat(2,puntosy1catrev11,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
  
       end

      if (app.cicloscatRT(seedlings)==1) & (contador10<=end1) & (contador10>start1)
        
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev11=cat(2,puntosxcatrev11,points(:,1));
      puntosycatrev11=cat(2,puntosycatrev11,points(:,2));   
        
      puntosx1catrev11=cat(2,puntosx1catrev11,points1(:,1));
      puntosy1catrev11=cat(2,puntosy1catrev11,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end


       if (app.cicloscatRT(seedlings)==2) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
      objectFrame = step(videoFileReaderrev);
      contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,3});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev21=cat(2,puntosxcatrev21,points(:,1));
      puntosycatrev21=cat(2,puntosycatrev21,points(:,2));   
        
      puntosx1catrev21=cat(2,puntosx1catrev21,points1(:,1));
      puntosy1catrev21=cat(2,puntosy1catrev21,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      
       end
      
      if (app.cicloscatRT(seedlings)==2) & (contador10>start1) & (contador10<=end1)
 

      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev21=cat(2,puntosxcatrev21,points(:,1));
      puntosycatrev21=cat(2,puntosycatrev21,points(:,2));   
        
      puntosx1catrev21=cat(2,puntosx1catrev21,points1(:,1));
      puntosy1catrev21=cat(2,puntosy1catrev21,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end


      end
      

       if (app.cicloscatRT(seedlings)==2) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
      objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);  
      
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev22=cat(2,puntosxcatrev22,points(:,1));
      puntosycatrev22=cat(2,puntosycatrev22,points(:,2));   
        
      puntosx1catrev22=cat(2,puntosx1catrev22,points1(:,1));
      puntosy1catrev22=cat(2,puntosy1catrev22,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end


       end
       
       if app.cicloscatRT(seedlings)==2 & contador10>=end1 & contador10<=end2
      
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev22=cat(2,puntosxcatrev22,points(:,1));
      puntosycatrev22=cat(2,puntosycatrev22,points(:,2));   
        
      puntosx1catrev22=cat(2,puntosx1catrev22,points1(:,1));
      puntosy1catrev22=cat(2,puntosy1catrev22,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end


       if (app.cicloscatRT(seedlings)==3) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
         objectFrame = step(videoFileReaderrev);
         contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,4});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
     
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev31=cat(2,puntosxcatrev31,points(:,1));
      puntosycatrev31=cat(2,puntosycatrev31,points(:,2));   
        
      puntosx1catrev31=cat(2,puntosx1catrev31,points1(:,1));
      puntosy1catrev31=cat(2,puntosy1catrev31,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end


       end
      
      if app.cicloscatRT(seedlings)==3 & contador10>start1 & contador10<=end1
      
      frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
     
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev31=cat(2,puntosxcatrev31,points(:,1));
      puntosycatrev31=cat(2,puntosycatrev31,points(:,2));   
        
      puntosx1catrev31=cat(2,puntosx1catrev31,points1(:,1));
      puntosy1catrev31=cat(2,puntosy1catrev31,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end
      
      
      if (app.cicloscatRT(seedlings)==3) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,3});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
       [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev32=cat(2,puntosxcatrev32,points(:,1));
      puntosycatrev32=cat(2,puntosycatrev32,points(:,2));   
        
      puntosx1catrev32=cat(2,puntosx1catrev32,points1(:,1));
      puntosy1catrev32=cat(2,puntosy1catrev32,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      
       end
      if app.cicloscatRT(seedlings)==3 & contador10>=end1 & contador10<=end2

       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev32=cat(2,puntosxcatrev32,points(:,1));
      puntosycatrev32=cat(2,puntosycatrev32,points(:,2));   
        
      puntosx1catrev32=cat(2,puntosx1catrev32,points1(:,1));
      puntosy1catrev32=cat(2,puntosy1catrev32,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end
      
      
      if (app.cicloscatRT(seedlings)==3) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        objectFrame = frame;
        
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);


      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev33=cat(2,puntosxcatrev33,points(:,1));
      puntosycatrev33=cat(2,puntosycatrev33,points(:,2));   
        
      puntosx1catrev33=cat(2,puntosx1catrev33,points1(:,1));
      puntosy1catrev33=cat(2,puntosy1catrev33,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      
       end
      if app.cicloscatRT(seedlings)==3 & contador10>=end2 & contador10<=end3
      
          frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);


      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev33=cat(2,puntosxcatrev33,points(:,1));
      puntosycatrev33=cat(2,puntosycatrev33,points(:,2));   
        
      puntosx1catrev33=cat(2,puntosx1catrev33,points1(:,1));
      puntosy1catrev33=cat(2,puntosy1catrev33,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end


      if (app.cicloscatRT(seedlings)==4) & (contador10==start1)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = step(videoFileReaderrev);
        contador10=contador10+1;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,5});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,5});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev41=cat(2,puntosxcatrev41,points(:,1));
      puntosycatrev41=cat(2,puntosycatrev41,points(:,2));   
        
      puntosx1catrev41=cat(2,puntosx1catrev41,points1(:,1));
      puntosy1catrev41=cat(2,puntosy1catrev41,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end


       end
      if app.cicloscatRT(seedlings)==4 & contador10>start1 & contador10<=end1
       frame = step(videoFileReaderrev);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);

      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev41=cat(2,puntosxcatrev41,points(:,1));
      puntosycatrev41=cat(2,puntosycatrev41,points(:,2));   
        
      puntosx1catrev41=cat(2,puntosx1catrev41,points1(:,1));
      puntosy1catrev41=cat(2,puntosy1catrev41,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end


      if (app.cicloscatRT(seedlings)==4) & (contador10==end1)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,4});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,4});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev42=cat(2,puntosxcatrev42,points(:,1));
      puntosycatrev42=cat(2,puntosycatrev42,points(:,2));   
        
      puntosx1catrev42=cat(2,puntosx1catrev42,points1(:,1));
      puntosy1catrev42=cat(2,puntosy1catrev42,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      
      end
      if app.cicloscatRT(seedlings)==4 & contador10>=end1 & contador10<=end2
       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev42=cat(2,puntosxcatrev42,points(:,1));
      puntosycatrev42=cat(2,puntosycatrev42,points(:,2));   
        
      puntosx1catrev42=cat(2,puntosx1catrev42,points1(:,1));
      puntosy1catrev42=cat(2,puntosy1catrev42,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end
      
      if (app.cicloscatRT(seedlings)==4) & (contador10==end2)
      % Read the first video frame, which contains the object, define the region.
        
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,3});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,3});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev43=cat(2,puntosxcatrev43,points(:,1));
      puntosycatrev43=cat(2,puntosycatrev43,points(:,2));   
        
      puntosx1catrev43=cat(2,puntosx1catrev43,points1(:,1));
      puntosy1catrev43=cat(2,puntosy1catrev43,points1(:,2));     
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      
       end
      
      if app.cicloscatRT(seedlings)==4 & contador10>=end2 & contador10<=end3
       frame = step(videoFileReaderrev);
      contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev43=cat(2,puntosxcatrev43,points(:,1));
      puntosycatrev43=cat(2,puntosycatrev43,points(:,2));   
        
      puntosx1catrev43=cat(2,puntosx1catrev43,points1(:,1));
      puntosy1catrev43=cat(2,puntosy1catrev43,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end
      
      
      if (app.cicloscatRT(seedlings)==4) & (contador10==end3)
      % Read the first video frame, which contains the object, define the region.
       
        objectFrame = frame;
      points = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncatRT{seedlings,2});
      points1 = detectMinEigenFeatures(rgb2gray(objectFrame),'ROI',app.objectRegioncat1RT{seedlings,2});
      tracker = vision.PointTracker(app.metodos,app.metodosvalue);
      tracker1 = vision.PointTracker(app.metodos,app.metodosvalue);
      initialize(tracker,points.Location,objectFrame);
      initialize(tracker1,points1.Location,objectFrame);
      
      [points, validity] = step(tracker,objectFrame);
      [points1, validity1] = step(tracker1,objectFrame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(objectFrame,points3(validity3, :),'+');
      
      puntosxcatrev44=cat(2,puntosxcatrev44,points(:,1));
      puntosycatrev44=cat(2,puntosycatrev44,points(:,2));   
        
      puntosx1catrev44=cat(2,puntosx1catrev44,points1(:,1));
      puntosy1catrev44=cat(2,puntosy1catrev44,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end


       end
      if app.cicloscatRT(seedlings)==4 & contador10>=end3 & contador10<=end4
       frame = step(videoFileReaderrev);
       contador10=contador10+1;
      [points, validity] = step(tracker,frame);
      [points1, validity1] = step(tracker1,frame);
      
      points3 = cat(1,points,points1);
      validity3 = cat(1,validity,validity1);
     
     
      out = insertMarker(frame,points3(validity3, :),'+');
      
      puntosxcatrev44=cat(2,puntosxcatrev44,points(:,1));
      puntosycatrev44=cat(2,puntosycatrev44,points(:,2));   
        
      puntosx1catrev44=cat(2,puntosx1catrev44,points1(:,1));
      puntosy1catrev44=cat(2,puntosy1catrev44,points1(:,2));     
      
%       if visual==1
%           imshow(out);
%           drawnow;
%       end
      
      if app.saveRT==1
      writeVideo(writerObj1rev, out);
      end
      end
      
   if contador10<start1
        framex = step(videoFileReaderrev);
       contador10=contador10+1;
   end
   
 if app.cicloscatRT(seedlings)==1 & contador10>end1 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end
  
 if app.cicloscatRT(seedlings)==2 & contador10>end2 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end    
 
 if app.cicloscatRT(seedlings)==3 & contador10>end3 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end
  
 if app.cicloscatRT(seedlings)==4 & contador10>end4 & contador10<=numberofframes{seedlings}
     framex = step(videoFileReaderrev);
     contador10=contador10+1;
     
 end


%if contador10==numberofframes{seedlings}
%    break
%end
end
%% 
% Release the video reader and player.
release(videoPlayerrev);
release(videoPlayer1rev);
release(videoFileReaderrev);
close(writerObj1rev);


end


test=0;


if (app.cicloscatRT(seedlings)==1)
  [results,angle1cat]=app.growthcalculatorRT(puntosxcat11,puntosx1cat11,puntosycat11,puntosy1cat11,timefilename,start1,app.visualRT);
  if app.reverseRT==1
      [resultsrev,angleresultsrev]=app.growthcalculatorRT(puntosxcatrev11,puntosx1catrev11,puntosycatrev11,puntosy1catrev11,timefilenamerev,start1,app.visualRT);
      resultsrev=flipud(resultsrev);
      resultsmix=results;
      resultsmix=(resultsmix+resultsrev)/2;
      
      angleresultsrev=flipud(angleresultsrev);
      angleresultsmix=angle1cat;
      angleresultsmix=(angleresultsmix+angleresultsrev)/2;
      
      destino16=strcat(app.pathnamecatRT{seedlings},'AnglesRev.csv');
      csvwrite(destino16,angleresultsrev);
      
  end
destino15=strcat(app.pathnamecatRT{seedlings},'Angles.csv');
csvwrite(destino15,angle1cat);


end

if (app.cicloscatRT(seedlings)==2)
  results21=app.growthcalculatorRT(puntosxcat21,puntosx1cat21,puntosycat21,puntosy1cat21,timefilename,start1,app.visualRT);  
  results22=app.growthcalculatorRT(puntosxcat22,puntosx1cat22,puntosycat22,puntosy1cat22,timefilename,end1,app.visualRT); 
  results=cat(1,results21,results22);
  
  if app.reverseRT==1
      resultsrev21=app.growthcalculatorRT(puntosxcatrev21,puntosx1catrev21,puntosycatrev21,puntosy1catrev21,timefilenamerev,start1,app.visualRT);  
      resultsrev22=app.growthcalculatorRT(puntosxcatrev22,puntosx1catrev22,puntosycatrev22,puntosy1catrev22,timefilenamerev,end1,app.visualRT); 
      resultsrev=cat(1,resultsrev21,resultsrev22);
      resultsrev=flipud(resultsrev);
      resultsmix=results;
      if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix+resultsrev)/2;
      test=1;
      end
  end

end

if (app.cicloscatRT(seedlings)==3)
  results31=app.growthcalculatorRT(puntosxcat31,puntosx1cat31,puntosycat31,puntosy1cat31,timefilename,start1,app.visualRT);  
  results32=app.growthcalculatorRT(puntosxcat32,puntosx1cat32,puntosycat32,puntosy1cat32,timefilename,end1,app.visualRT); 
  results33=app.growthcalculatorRT(puntosxcat33,puntosx1cat33,puntosycat33,puntosy1cat33,timefilename,end2,app.visualRT); 
  results=cat(1,results31,results32,results33);
  
  if app.reverseRT==1
      resultsrev31=app.growthcalculatorRT(puntosxcatrev31,puntosx1catrev31,puntosycatrev31,puntosy1catrev31,timefilenamerev,start1,app.visualRT);  
      resultsrev32=app.growthcalculatorRT(puntosxcatrev32,puntosx1catrev32,puntosycatrev32,puntosy1catrev32,timefilenamerev,end1,app.visualRT); 
      resultsrev33=app.growthcalculatorRT(puntosxcatrev33,puntosx1catrev33,puntosycatrev33,puntosy1catrev33,timefilenamerev,end2,app.visualRT); 
      resultsrev=cat(1,resultsrev31,resultsrev32,resultsrev33);
      resultsrev=flipud(resultsrev);
      resultsmix=results;
      if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix+resultsrev)/2;
      test=1;
      end
  end
  
end


if (app.cicloscatRT(seedlings)==4)
  results41=app.growthcalculatorRT(puntosxcat41,puntosx1cat41,puntosycat41,puntosy1cat41,timefilename,start1,app.visualRT);  
  results42=app.growthcalculatorRT(puntosxcat42,puntosx1cat42,puntosycat42,puntosy1cat42,timefilename,end1,app.visualRT); 
  results43=app.growthcalculatorRT(puntosxcat43,puntosx1cat43,puntosycat43,puntosy1cat43,timefilename,end2,app.visualRT); 
  results44=app.growthcalculatorRT(puntosxcat44,puntosx1cat44,puntosycat44,puntosy1cat44,timefilename,end3,app.visualRT); 
  results=cat(1,results41,results42,results43,results44);
  
  
  if app.reverseRT==1
      resultsrev41=app.growthcalculatorRT(puntosxcatrev41,puntosx1catrev41,puntosycatrev41,puntosy1catrev41,timefilenamerev,start1,app.visualRT);  
      resultsrev42=app.growthcalculatorRT(puntosxcatrev42,puntosx1catrev42,puntosycatrev42,puntosy1catrev42,timefilenamerev,end1,app.visualRT); 
      resultsrev43=app.growthcalculatorRT(puntosxcatrev43,puntosx1catrev43,puntosycatrev43,puntosy1catrev43,timefilenamerev,end2,app.visualRT); 
      resultsrev44=app.growthcalculatorRT(puntosxcatrev44,puntosx1catrev44,puntosycatrev44,puntosy1catrev44,timefilenamerev,end3,app.visualRT); 
      resultsrev=cat(1,resultsrev41,resultsrev42,resultsrev43,resultsrev44);
      resultsrev=flipud(resultsrev);
      resultsmix=results;
      if numel(resultsmix)==numel(resultsrev)  
      resultsmix=(resultsmix+resultsrev)/2;
      test=1;
      end
  end


end


destino1=strcat(app.pathnamecatRT{seedlings},app.listofgenotypesRT{seedlings},'F.csv');
destino12=strcat(app.pathnamecatRT{seedlings},app.listofgenotypesRT{seedlings},'R.csv');
destino11=strcat(app.pathnamecatRT{seedlings},'CSV.csv');
csvwrite(destino1,results);
if app.reverseRT==1
    if test==1
csvwrite(destino11,resultsmix);
    end
csvwrite(destino12,resultsrev);
else
    csvwrite(destino11,results);

end

end


app.EditField.Value='ALL DONE';
           
        end

        % Callback function
        function FrametoajustconstrastEditField_2ValueChanged(app, event)
            app.FRAMEcorrection = app.FrametoajustconstrastEditField_2.Value;
            
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.metodos = app.MethodDropDown.Value;
            app.metodosvalue=1;
            test=strcmp(app.metodos,'NumPyramidLevels');
            test1=strcmp(app.metodos,'MaxBidirectionalError');
            test2=strcmp(app.metodos,'BlockSize');
            test3=strcmp(app.metodos,'MaxIterations');
 if test==1 || test1 ==1    
   app.metodosvalue = inputdlg('recomended value 1');
 end
  if test2==1 
   app.metodosvalue = inputdlg('recomended value 5 5');   
 end
 if test3==1 
   app.metodosvalue = inputdlg('recomended value 30');  
 end
 
app.metodosvalue=str2double(app.metodosvalue);

            
        end

        % Value changed function: MethodDropDown_2
        function MethodDropDown_2ValueChanged(app, event)
            app.metodosRT = app.MethodDropDown_2.Value;
     app.metodosvalue=1;
            test=strcmp(app.metodosRT,'NumPyramidLevels');
            test1=strcmp(app.metodosRT,'MaxBidirectionalError');
            test2=strcmp(app.metodosRT,'BlockSize');
            test3=strcmp(app.metodosRT,'MaxIterations');
 if test==1 || test1 ==1    
   app.metodosRTvalue = inputdlg('recomended value 1');
 end
  if test2==1 
   app.metodosRTvalue = inputdlg('recomended value 5 5');   
 end
 if test3==1 
   app.metodosRTvalue = inputdlg('recomended value 30');  
 end
 
app.metodosRTvalue=str2double(app.metodosRTvalue);       
        end

        % Button pushed function: SprayTreatmentScheduleButton
        function SprayTreatmentScheduleButtonPushed(app, event)
            prompt = {'Input the starting time and duration in minutes: '};
            title = 'Input';
            dims = [1 35];
            definput = {'240 2'};
            x1 = inputdlg(prompt,title,dims,definput);
           tiempos=split(x1(1,1),' ');
            app.timesspraying=tiempos;
        end

        % Button pushed function: CloseArduinoPortsButton
        function CloseArduinoPortsButtonPushed(app, event)
    if ~isempty(instrfind)
     fclose(instrfind);
      delete(instrfind);
    end

        end

        % Size changed function: Camera_ArduinoTab
        function Camera_ArduinoTabSizeChanged(app, event)
            position = app.Camera_ArduinoTab.Position;
            
        end

        % Button pushed function: UploadSketchstoArduinosButton
        function UploadSketchstoArduinosButtonPushed(app, event)
%              strOutput5='C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\bin\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\etc\avrdude.conf -p atmega328p -c arduino -PCOM5 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray3.ino.hex:i';
%              strOutput3='C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\bin\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\etc\avrdude.conf -p atmega328p -c arduino -PCOM3 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\DavidStepper3Dv8.ino.hex:i';
%              strOutput4='C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\bin\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\etc\avrdude.conf -p atmega328p -c arduino -PCOM4 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\DavidStepper3Dv8.ino.hex:i';
%              strOutput5='C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude.conf -p atmega328p -c arduino -PCOM5 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray3.ino.hex:i';
%              strOutput3='C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude.conf -p atmega328p -c arduino -PCOM3 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\DavidStepper3Dv8.ino.hex:i';
%              strOutput4='C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude -C C:\Users\Lab\Documents\MATLAB\DavidImages\avrdude.conf -p atmega328p -c arduino -PCOM4 -Uflash:w:C:\Users\Lab\Documents\MATLAB\DavidImages\DavidStepper3Dv8.ino.hex:i';
             strOutput5=strcat(app.avrdude,' -C ',app.avrdudeconf,' -p atmega328p -c arduino -P',app.ports(3,1),' -Uflash:w:',app.inoexe5,':i');
             strOutput3=strcat(app.avrdude,' -C ',app.avrdudeconf,' -p atmega328p -c arduino -P',app.ports(2,1),' -Uflash:w:',app.inoexe,':i');
             strOutput4=strcat(app.avrdude,' -C ',app.avrdudeconf,' -p atmega328p -c arduino -P',app.ports(1,1),' -Uflash:w:',app.inoexe,':i');


[status5,results5]=system(strOutput5{1});
             [status3,results3]=system(strOutput3{1});
             [status4,results4]=system(strOutput4{1});
    if ~isempty(instrfind)
     fclose(instrfind);
      delete(instrfind);
    end
             
             
        end

        % Button pushed function: RenameFilesButton
        function RenameFilesButtonPushed(app, event)
            %you need to have an excel file with the first column containing the name of the original files and the second column the name of the corresponding genotypes
%just like this (Note each column has to have a title)

%Raw Image title 	Align image title
%Seedling.tif2.     Col1
%Seedling.tif3.     Col2
%Seedling.tif5.     Col3
%Seedling.tif6.     Col4
%Seedling.tif7.     Col5
%Seedling.tif8.     Col6
%Seedling.tif9.     Col7

%etc


%function FileRename()

app.EditFieldMessage.Value='Upload and excel file with the first column containing the original names and the second column the corresponding new names';
[FileName,PathName] = uigetfile('*.*');
name=strcat(PathName,FileName);
table=readtable(name);
ncolumns=size(table);
app.EditFieldMessage.Value='Select a file in the directory where you have the original files';
[FileName1,PathName1] = uigetfile('*.*');

for x = 1:ncolumns(1,1)
    newname1=strcat(PathName1,table{x,1});
    newname2=table{x,1};
    genotype=table{x,2};
    genotype=char(genotype);
    
    newdir=strcat(PathName1,'/',table{x,2});
    
    newdir=char(newdir);
    newdirRaw=strcat(newdir,'/','Raw');
    
    newname1=char(newname1);
    newname2=char(newname2);
    newdirGenotype=strcat(newdir,'/',genotype,'/');
    mkdir(newdirGenotype);
    mkdir(newdir);
    mkdir(newdirRaw);
    for p=0:1000
        
        number=num2str(p);
        newname1andnumber=strcat(newname1,number,'.tif');
        if exist (newname1andnumber,'file')
            newname2andnumber=strcat(newdirRaw,'/',newname2,number,'.tif');
            movefile(newname1andnumber,newname2andnumber);
        else
            break
        end
    end


end
%end
        end

        % Button pushed function: RenameFilesButton_2
        function RenameFilesButton_2Pushed(app, event)
             %you need to have an excel file with the first column containing the name of the original files and the second column the name of the corresponding genotypes
%just like this (Note each column has to have a title)

%Raw Image title 	Align image title
%Seedling.tif2.     Col1
%Seedling.tif3.     Col2
%Seedling.tif5.     Col3
%Seedling.tif6.     Col4
%Seedling.tif7.     Col5
%Seedling.tif8.     Col6
%Seedling.tif9.     Col7

%etc


%function FileRename()

app.EditField.Value='Upload and excel file with the first column containing the original names and the second column the corresponding new names';
[FileName,PathName] = uigetfile('*.*');
name=strcat(PathName,FileName);
table=readtable(name);
ncolumns=size(table);
app.EditField.Value='Select a file in the directory where you have the original files';
[FileName1,PathName1] = uigetfile('*.*');

for x = 1:ncolumns(1,1)
    newname1=strcat(PathName1,table{x,1});
    newname2=table{x,1};
    genotype=table{x,2};
    genotype=char(genotype);
    
    newdir=strcat(PathName1,'/',table{x,2});
    
    newdir=char(newdir);
    newdirRaw=strcat(newdir,'/','Raw');
    
    newname1=char(newname1);
    newname2=char(newname2);
    newdirGenotype=strcat(newdir,'/',genotype,'/');
    mkdir(newdirGenotype);
    mkdir(newdir);
    mkdir(newdirRaw);
    for p=0:1000
        
        number=num2str(p);
        newname1andnumber=strcat(newname1,number,'.tif');
        if exist (newname1andnumber,'file')
            newname2andnumber=strcat(newdirRaw,'/',newname2,number,'.tif');
            movefile(newname1andnumber,newname2andnumber);
        else
            break
        end
    end


end
%end
        end

        % Button pushed function: UploadSketchstoArduinosButton_2
        function UploadSketchstoArduinosButton_2Pushed(app, event)

            
            prompt = {'Input the path to the file avrdude: '};
            title = 'Input';
            dims = [1 100];
            app.avrdude = {'C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\bin\avrdude'};
            app.avrdude = inputdlg(prompt,title,dims, app.avrdude);

            prompt = {'Input the path to the file avrdude.conf: '};
            title = 'Input';
            dims = [1 100];
            app.avrdudeconf = {'C:\Users\Lab\Documents\MATLAB\DavidImages\arduino-nightly-windows\arduino-nightly\hardware\tools\avr\etc\avrdude.conf'};
            app.avrdudeconf = inputdlg(prompt,title,dims, app.avrdudeconf);
           
            prompt = {'Input the path to the file ino.hex for the arduino Valves and Sprayers sketch: '};
            title = 'Input';
            dims = [1 100];
            app.inoexe5 = {'C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray3.ino.hex'};
            app.inoexe5 = inputdlg(prompt,title,dims, app.inoexe5);
            
            prompt = {'Input the path to the file ino.hex for the arduino stepper motor sketch: '};
            title = 'Input';
            dims = [1 100];
            app.inoexe = {'C:\Users\Lab\Documents\MATLAB\DavidImages\ValvesANDSpray.ino.hex'};
            app.inoexe = inputdlg(prompt,title,dims, app.inoexe);
            %app.times=app.times;
        end

        % Value changed function: 
        % Normalizegrowthto2hbeforetreatmentCheckBox
        function Normalizegrowthto2hbeforetreatmentCheckBoxValueChanged(app, event)
            value = app.Normalizegrowthto2hbeforetreatmentCheckBox.Value;
            app.autonorm=value;
        end

        % Value changed function: 
        % StartpointfornormalizedgrowthEditField
        function StartpointfornormalizedgrowthEditFieldValueChanged(app, event)
            value = app.StartpointfornormalizedgrowthEditField.Value;
            app.inicostart=value;
        end

        % Value changed function: 
        % EndpointfornormalizedgrowthEditField
        function EndpointfornormalizedgrowthEditFieldValueChanged(app, event)
            value = app.EndpointfornormalizedgrowthEditField.Value;
            app.inicioend=value;
        end

        % Value changed function: AjustcontrastCheckBox
        function AjustcontrastCheckBoxValueChanged(app, event)
           value = app.AjustcontrastCheckBox.Value;
           app.FRAMEcorrection=value;
        end

        % Value changed function: AjustcontrastCheckBox_2
        function AjustcontrastCheckBox_2ValueChanged(app, event)
            value = app.AjustcontrastCheckBox_2.Value;
            app.FRAMEcorrection=value;
        end

        % Value changed function: PlotapointeveryxminutesEditField
        function PlotapointeveryxminutesEditFieldValueChanged(app, event)
            value = app.PlotapointeveryxminutesEditField.Value;
            app.intervalo=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Camera_ArduinoTab = uitab(app.TabGroup);
            app.Camera_ArduinoTab.SizeChangedFcn = createCallbackFcn(app, @Camera_ArduinoTabSizeChanged, true);
            app.Camera_ArduinoTab.Title = 'Camera_Arduino';
            app.UIAxes_3 = uiaxes(app.Camera_ArduinoTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.GasTreatmentScheduleButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.GasTreatmentScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @GasTreatmentScheduleButtonPushed, true);
            app.GasTreatmentScheduleButton.Text = 'Gas Treatment Schedule';
            app.TextArea_19 = uitextarea(app.Camera_ArduinoTab);
            app.TextArea_19.Value = {'Takes the duration of each treatment (in minutes). By default delivers 240min of air, 120 of ethylene followed  by another 240min of air, then stops.'};
            app.PortsButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.PortsButton.ButtonPushedFcn = createCallbackFcn(app, @PortsButtonPushed, true);
            app.PortsButton.Text = 'Ports';
            app.TextArea_20 = uitextarea(app.Camera_ArduinoTab);
            app.TextArea_20.Value = {'Enter the ports used to connect with the arduinos.'; 'Camera1 (COM3), Camera2 (COM4), Valves (COM5)'};
            app.SetupcamerasButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.SetupcamerasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupcamerasButtonPushed, true);
            app.SetupcamerasButton.Text = 'Set up cameras';
            app.TextArea_21 = uitextarea(app.Camera_ArduinoTab);
            app.TextArea_21.Value = {'Set up the cameras using the MatLab Imaqtool app and save the setting in a vid.mat file'; 'After saving the seetings and while in ''Preview'' mode use the ardunio buttons to record the position of the seedlings. '; 'When done, close the ImaqTool app and click "Start" in the arduinos controlling the cameras.'; 'Change picture frequency or click ''Run'' below.'; ''};
            app.PicturefrequencyButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.PicturefrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @PicturefrequencyButtonPushed, true);
            app.PicturefrequencyButton.Text = 'Picture frequency';
            app.TextArea_22 = uitextarea(app.Camera_ArduinoTab);
            app.TextArea_22.Value = {'AFTER setting up the camera use this button to change the time interval between two frames in a timeseries if different than 6 min.'; ''};
            app.RUNButton_3 = uibutton(app.Camera_ArduinoTab, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Text = 'RUN';
            app.TimeTextAreaLabel = uilabel(app.Camera_ArduinoTab);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.Camera_ArduinoTab);
            app.SeedlingTextAreaLabel = uilabel(app.Camera_ArduinoTab);
            app.SeedlingTextAreaLabel.Text = 'Seedling';
            app.SeedlingTextArea = uitextarea(app.Camera_ArduinoTab);
            app.FrameTextAreaLabel = uilabel(app.Camera_ArduinoTab);
            app.FrameTextAreaLabel.Text = 'Frame';
            app.FrameTextArea = uitextarea(app.Camera_ArduinoTab);
            app.CameraTextAreaLabel = uilabel(app.Camera_ArduinoTab);
            app.CameraTextAreaLabel.Text = 'Camera';
            app.CameraTextArea = uitextarea(app.Camera_ArduinoTab);
            app.SprayTreatmentScheduleButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.SprayTreatmentScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @SprayTreatmentScheduleButtonPushed, true);
            app.SprayTreatmentScheduleButton.Text = 'Spray Treatment Schedule';
            app.TextArea_25 = uitextarea(app.Camera_ArduinoTab);
            app.TextArea_25.Value = {'Takes starting time and duration (in minutes). By default it starts at 240min and last for 2min'};
            app.CloseArduinoPortsButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.CloseArduinoPortsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseArduinoPortsButtonPushed, true);
            app.CloseArduinoPortsButton.Text = 'Close Arduino Ports';
            app.UploadSketchstoArduinosButton = uibutton(app.Camera_ArduinoTab, 'push');
            app.UploadSketchstoArduinosButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSketchstoArduinosButtonPushed, true);
            app.UploadSketchstoArduinosButton.Text = 'Upload Sketchs to Arduinos';
            app.UploadSketchstoArduinosButton_2 = uibutton(app.Camera_ArduinoTab, 'push');
            app.UploadSketchstoArduinosButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadSketchstoArduinosButton_2Pushed, true);
            app.UploadSketchstoArduinosButton_2.Text = 'Setup Arduino Paths (you only need to do this the first time you install this software in a new computer)';
            app.HypocotylTab = uitab(app.TabGroup);
            app.HypocotylTab.Title = 'Hypocotyl';
            app.UIAxes_2 = uiaxes(app.HypocotylTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.EditFieldMessage = uieditfield(app.HypocotylTab, 'text');
            app.EditFieldMessage.Editable = 'off';
            app.EditFieldMessage.Value = 'Please, follow the instructions displayed here';
            app.TextArea_9 = uitextarea(app.HypocotylTab);
            app.TextArea_9.Value = {'Initiate settings'};
            app.TextArea_10 = uitextarea(app.HypocotylTab);
            app.TextArea_10.Value = {'# of sections'};
            app.TextArea_11 = uitextarea(app.HypocotylTab);
            app.TextArea_11.Value = {'Analyze forward & reverse'};
            app.DropDownSectionsHY = uidropdown(app.HypocotylTab);
            app.DropDownSectionsHY.Items = {'1', '2', '3', '4'};
            app.DropDownSectionsHY.ValueChangedFcn = createCallbackFcn(app, @DropDownSectionsHYValueChanged, true);
            app.DropDownSectionsHY.Value = '1';
            app.SwitchFRHY = uiswitch(app.HypocotylTab, 'slider');
            app.SwitchFRHY.Items = {'Forward', 'Forward&Reverse'};
            app.SwitchFRHY.ValueChangedFcn = createCallbackFcn(app, @SwitchFRHYValueChanged, true);
            app.SwitchFRHY.Value = 'Forward';
            app.TextArea_12 = uitextarea(app.HypocotylTab);
            app.TextArea_12.Value = {'# of points to select'};
            app.DropDownPointsHY = uidropdown(app.HypocotylTab);
            app.DropDownPointsHY.Items = {'10', '12', '14', '16', '18', '20', '22', '24', '26', '28', '30', '40', '2'};
            app.DropDownPointsHY.ValueChangedFcn = createCallbackFcn(app, @DropDownPointsHYValueChanged, true);
            app.DropDownPointsHY.Value = '10';
            app.TextArea_13 = uitextarea(app.HypocotylTab);
            app.TextArea_13.Value = {'# of SETS of points'};
            app.DropDownSetsHY = uidropdown(app.HypocotylTab);
            app.DropDownSetsHY.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DropDownSetsHY.ValueChangedFcn = createCallbackFcn(app, @DropDownSetsHYValueChanged, true);
            app.DropDownSetsHY.Value = '1';
            app.TextArea_14 = uitextarea(app.HypocotylTab);
            app.TextArea_14.Value = {'Visualize analysis as it goes?'};
            app.TextArea_15 = uitextarea(app.HypocotylTab);
            app.TextArea_15.Value = {'Save movies?'};
            app.SwitchVisHY = uiswitch(app.HypocotylTab, 'slider');
            app.SwitchVisHY.Items = {'No', 'Yes'};
            app.SwitchVisHY.ValueChangedFcn = createCallbackFcn(app, @SwitchVisHYValueChanged, true);
            app.SwitchVisHY.Value = 'No';
            app.SwitchMovHY = uiswitch(app.HypocotylTab, 'slider');
            app.SwitchMovHY.Items = {'Yes', 'No'};
            app.SwitchMovHY.ValueChangedFcn = createCallbackFcn(app, @SwitchMovHYValueChanged, true);
            app.SwitchMovHY.Value = 'Yes';
            app.TextArea_16 = uitextarea(app.HypocotylTab);
            app.TextArea_16.Value = {'Total # of frames to analyze'};
            app.SelectlastframetoanalyzeButton_2 = uibutton(app.HypocotylTab, 'push');
            app.SelectlastframetoanalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectlastframetoanalyzeButton_2Pushed, true);
            app.SelectlastframetoanalyzeButton_2.Text = 'Select last frame to analyze';
            app.TextArea_17 = uitextarea(app.HypocotylTab);
            app.TextArea_17.Value = {'Calculate angles?'};
            app.SwitchAngleHY = uiswitch(app.HypocotylTab, 'slider');
            app.SwitchAngleHY.Items = {'No', 'Yes'};
            app.SwitchAngleHY.ValueChangedFcn = createCallbackFcn(app, @SwitchAngleHYValueChanged, true);
            app.SwitchAngleHY.Value = 'No';
            app.TextArea_18 = uitextarea(app.HypocotylTab);
            app.TextArea_18.Value = {'Start image analysis'};
            app.SelectfirstimageButton_2 = uibutton(app.HypocotylTab, 'push');
            app.SelectfirstimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfirstimageButton_2Pushed, true);
            app.SelectfirstimageButton_2.Text = 'Select first image';
            app.RUNButton_2 = uibutton(app.HypocotylTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.ListoffilesTextArea_3Label = uilabel(app.HypocotylTab);
            app.ListoffilesTextArea_3Label.Text = 'List of files';
            app.ListoffilesTextArea_3 = uitextarea(app.HypocotylTab);
            app.TextArea_23 = uitextarea(app.HypocotylTab);
            app.TextArea_23.Value = {'Tracking method'};
            app.MethodDropDown = uidropdown(app.HypocotylTab);
            app.MethodDropDown.Items = {'NumPyramidLevels', 'MaxBidirectionalError', 'BlockSize', 'MaxIterations'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'MaxBidirectionalError';
            app.RenameFilesButton = uibutton(app.HypocotylTab, 'push');
            app.RenameFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RenameFilesButtonPushed, true);
            app.RenameFilesButton.Text = 'Rename Files';
            app.AjustcontrastCheckBox_2 = uicheckbox(app.HypocotylTab);
            app.AjustcontrastCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AjustcontrastCheckBox_2ValueChanged, true);
            app.AjustcontrastCheckBox_2.Text = 'Ajust contrast';
            app.RootTab = uitab(app.TabGroup);
            app.RootTab.Title = 'Root';
            app.UIAxes = uiaxes(app.RootTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.EditField = uieditfield(app.RootTab, 'text');
            app.EditField.Value = 'Please, follow the instructions displayed here';
            app.TextArea = uitextarea(app.RootTab);
            app.TextArea.Value = {'# of sections'};
            app.TextArea_2 = uitextarea(app.RootTab);
            app.TextArea_2.Value = {'Analyze forward & reverse'};
            app.DropDown = uidropdown(app.RootTab);
            app.DropDown.Items = {'1', '2', '3', '4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.TextArea_3 = uitextarea(app.RootTab);
            app.TextArea_3.Value = {'Visualize analysis as it goes?'};
            app.TextArea_4 = uitextarea(app.RootTab);
            app.TextArea_4.Value = {'Save movies?'};
            app.Switch = uiswitch(app.RootTab, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'No';
            app.Switch_2 = uiswitch(app.RootTab, 'slider');
            app.Switch_2.Items = {'Yes', 'No'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Yes';
            app.Switch_3 = uiswitch(app.RootTab, 'slider');
            app.Switch_3.Items = {'Forward', 'Reverse'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Forward';
            app.TextArea_5 = uitextarea(app.RootTab);
            app.TextArea_5.Value = {'Total # of frames to analyze'};
            app.SelectlastframetoanalyzeButton = uibutton(app.RootTab, 'push');
            app.SelectlastframetoanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @SelectlastframetoanalyzeButtonPushed, true);
            app.SelectlastframetoanalyzeButton.Text = 'Select last frame to analyze';
            app.SelectfirstimageButton = uibutton(app.RootTab, 'push');
            app.SelectfirstimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfirstimageButtonPushed, true);
            app.SelectfirstimageButton.Text = 'Select first image';
            app.RUNButton = uibutton(app.RootTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ListoffilesTextAreaLabel = uilabel(app.RootTab);
            app.ListoffilesTextAreaLabel.Text = 'List of files';
            app.ListoffilesTextArea = uitextarea(app.RootTab);
            app.TextArea_24 = uitextarea(app.RootTab);
            app.TextArea_24.Value = {'Tracking method'};
            app.MethodDropDown_2 = uidropdown(app.RootTab);
            app.MethodDropDown_2.Items = {'MaxBidirectionalError', 'NumPyramidLevels', 'BlockSize', 'MaxIterations'};
            app.MethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MethodDropDown_2.Value = 'MaxBidirectionalError';
            app.RenameFilesButton_2 = uibutton(app.RootTab, 'push');
            app.RenameFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @RenameFilesButton_2Pushed, true);
            app.RenameFilesButton_2.Text = 'Rename Files';
            app.AjustcontrastCheckBox = uicheckbox(app.RootTab);
            app.AjustcontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @AjustcontrastCheckBoxValueChanged, true);
            app.AjustcontrastCheckBox.Text = 'Ajust contrast';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.UIAxes2 = uiaxes(app.SmoothingTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ListoffilesTextArea_2Label = uilabel(app.SmoothingTab);
            app.ListoffilesTextArea_2Label.Text = 'List of files';
            app.ListoffilesTextArea_2 = uitextarea(app.SmoothingTab);
            app.TextArea_6 = uitextarea(app.SmoothingTab);
            app.TextArea_6.Value = {'This needs to be done just for the first seedling of each experiment'};
            app.LoadfirstpicoftheexperimentButton = uibutton(app.SmoothingTab, 'push');
            app.LoadfirstpicoftheexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfirstpicoftheexperimentButtonPushed, true);
            app.LoadfirstpicoftheexperimentButton.Text = 'Load first pic of the experiment';
            app.LoadpicwhentreatmentstartedButton = uibutton(app.SmoothingTab, 'push');
            app.LoadpicwhentreatmentstartedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpicwhentreatmentstartedButtonPushed, true);
            app.LoadpicwhentreatmentstartedButton.Text = 'Load pic when treatment started';
            app.LoadpicwhentreatmentendedButton = uibutton(app.SmoothingTab, 'push');
            app.LoadpicwhentreatmentendedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpicwhentreatmentendedButtonPushed, true);
            app.LoadpicwhentreatmentendedButton.Text = 'Load pic when treatment ended';
            app.EditField_2 = uieditfield(app.SmoothingTab, 'text');
            app.EditField_2.Value = 'Start and end of treatment';
            app.TextArea_7 = uitextarea(app.SmoothingTab);
            app.TextArea_7.Value = {'The next two steps need to be done for each plant'};
            app.LoadfirstpicforthisplantButton = uibutton(app.SmoothingTab, 'push');
            app.LoadfirstpicforthisplantButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfirstpicforthisplantButtonPushed, true);
            app.LoadfirstpicforthisplantButton.Text = 'Load first pic for this plant';
            app.LoadgrowthfilecsvButton = uibutton(app.SmoothingTab, 'push');
            app.LoadgrowthfilecsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadgrowthfilecsvButtonPushed, true);
            app.LoadgrowthfilecsvButton.Text = 'Load growth file (csv)';
            app.EditField_3 = uieditfield(app.SmoothingTab, 'text');
            app.EditField_3.Value = 'Name of the last uploaded file';
            app.AddlastroottoAllRootsButton = uibutton(app.SmoothingTab, 'push');
            app.AddlastroottoAllRootsButton.ButtonPushedFcn = createCallbackFcn(app, @AddlastroottoAllRootsButtonPushed, true);
            app.AddlastroottoAllRootsButton.Text = 'Add last root to All-Roots';
            app.TextArea_8 = uitextarea(app.SmoothingTab);
            app.TextArea_8.Value = {'The next two steps need to be done for each plant'};
            app.SmoothingSplineofAllRootsButton = uibutton(app.SmoothingTab, 'push');
            app.SmoothingSplineofAllRootsButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothingSplineofAllRootsButtonPushed, true);
            app.SmoothingSplineofAllRootsButton.Text = 'Smoothing Spline of All-Roots';
            app.ExportResultsButton = uibutton(app.SmoothingTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.Normalizegrowthto2hbeforetreatmentCheckBox = uicheckbox(app.SmoothingTab);
            app.Normalizegrowthto2hbeforetreatmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @Normalizegrowthto2hbeforetreatmentCheckBoxValueChanged, true);
            app.Normalizegrowthto2hbeforetreatmentCheckBox.Text = 'Normalize growth to 2h before treatment';
            app.Normalizegrowthto2hbeforetreatmentCheckBox.Value = true;
            app.StartpointfornormalizedgrowthEditFieldLabel = uilabel(app.SmoothingTab);
            app.StartpointfornormalizedgrowthEditFieldLabel.Text = 'Start point for normalized growth';
            app.StartpointfornormalizedgrowthEditField = uieditfield(app.SmoothingTab, 'numeric');
            app.StartpointfornormalizedgrowthEditField.ValueChangedFcn = createCallbackFcn(app, @StartpointfornormalizedgrowthEditFieldValueChanged, true);
            app.StartpointfornormalizedgrowthEditField.Value = 120;
            app.TextArea_26 = uitextarea(app.SmoothingTab);
            app.TextArea_26.Value = {'Manually select the period used to normalized growth rates'};
            app.EndpointfornormalizedgrowthEditFieldLabel = uilabel(app.SmoothingTab);
            app.EndpointfornormalizedgrowthEditFieldLabel.Text = 'End point for normalized growth';
            app.EndpointfornormalizedgrowthEditField = uieditfield(app.SmoothingTab, 'numeric');
            app.EndpointfornormalizedgrowthEditField.ValueChangedFcn = createCallbackFcn(app, @EndpointfornormalizedgrowthEditFieldValueChanged, true);
            app.EndpointfornormalizedgrowthEditField.Value = 240;
            app.PlotapointeveryxminutesEditFieldLabel = uilabel(app.SmoothingTab);
            app.PlotapointeveryxminutesEditFieldLabel.Text = 'Plot a point every (x) minutes';
            app.PlotapointeveryxminutesEditField = uieditfield(app.SmoothingTab, 'numeric');
            app.PlotapointeveryxminutesEditField.ValueChangedFcn = createCallbackFcn(app, @PlotapointeveryxminutesEditFieldValueChanged, true);
            app.PlotapointeveryxminutesEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorphDPCMAc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end